classdef DCMotorSimulator < matlab.apps.AppBase
% DCMOTORSIMULATOR  Dynamic simulator for an armature-controlled DC motor.
%
%   EEE 212 Numerical Techniques Laboratory - Final Project
%
%   WHAT IT DOES
%   Solves the coupled electrical and mechanical equations of a DC motor
%   numerically and presents the result through an App Designer interface.
%
%       Va = La*dia/dt + Ra*ia + Ke*w        (armature circuit)
%       J*dw/dt        = Kt*ia - B*w - TL    (rotor dynamics)
%       dtheta/dt      = w                   (shaft position)
%
%   States integrated: [ia; omega; theta; T_winding]. The fourth state is
%   the winding temperature, which feeds back into Ra when the thermal
%   model is enabled, so it has to be solved alongside the others.
%
%   NUMERICAL METHODS
%   Forward Euler and classical RK4 are implemented directly (see
%   runEulerFixedStep / runRK4FixedStep), plus an adaptive RK45 pair.
%   ode45 appears only in the Time-Step Study, as an independent reference
%   for measuring error - never as the project's solver.
%
%   HOW THE CODE IS ORGANISED
%     Static methods  - the simulation engine. No UI at all, so it can be
%                       called and tested without the app running.
%     Private methods - glue: read the inputs, build the waveform handles,
%                       draw the plots, run the animation.
%     Callbacks       - one per button or drop-down.
%     createComponents- generated by App Designer. Do not hand-edit.
%
%   WHERE TO START READING
%     motorDerivative   - all of the physics, in one short function
%     simulateDCMotor   - picks a solver and assembles the results
%     updatePlots       - turns the results into the required plots

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnableCoulombFrictionCheckBox   matlab.ui.control.CheckBox
        EnableThermalModelCheckBox      matlab.ui.control.CheckBox
        ImportMeasuredDataButton        matlab.ui.control.Button
        MaxCurrentAEditField            matlab.ui.control.NumericEditField
        MaxCurrentAEditFieldLabel       matlab.ui.control.Label
        EnableCurrentLimitCheckBox      matlab.ui.control.CheckBox
        RunSimulationButton             matlab.ui.control.Button
        StatusLabel_2                   matlab.ui.control.Label
        TimeStepStudyButton             matlab.ui.control.Button
        StatusLabel_3                   matlab.ui.control.Label
        CompareMethodsButton            matlab.ui.control.Button
        StatusLabel_4                   matlab.ui.control.Label
        ImportWaveformButton            matlab.ui.control.Button
        ExportResultsButton_2           matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AnimationTab                    matlab.ui.container.Tab
        Time000sLabel                   matlab.ui.control.Label
        TempLabel                       matlab.ui.control.Label
        TorqueSpeedAxes                 matlab.ui.control.UIAxes
        AnimationAxes                   matlab.ui.control.UIAxes
        ElectricalTab                   matlab.ui.container.Tab
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        MechanicalTab                   matlab.ui.container.Tab
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        AnalysisTab                     matlab.ui.container.Tab
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        DataTable                       matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ResetAllButton                  matlab.ui.control.Button
        SimulationInputsPanel           matlab.ui.container.Panel
        LoadTorqueProfileDropDown       matlab.ui.control.DropDown
        LoadTorqueProfileDropDownLabel  matlab.ui.control.Label
        NumericalMethodDropDown         matlab.ui.control.DropDown
        NumericalMethodDropDownLabel    matlab.ui.control.Label
        VoltageWaveformDropDown         matlab.ui.control.DropDown
        VoltageWaveformDropDownLabel    matlab.ui.control.Label
        TimeStepEditField               matlab.ui.control.NumericEditField
        TimeStepEditFieldLabel          matlab.ui.control.Label
        SimulationDurationEditField     matlab.ui.control.NumericEditField
        SimulationDurationEditFieldLabel  matlab.ui.control.Label
        LoadApplicationTimeEditField    matlab.ui.control.NumericEditField
        LoadApplicationTimeEditFieldLabel  matlab.ui.control.Label
        LoadTorqueMagnitudeEditField    matlab.ui.control.NumericEditField
        LoadTorqueMagnitudeEditFieldLabel  matlab.ui.control.Label
        SupplyVoltageMagnitudeEditField  matlab.ui.control.NumericEditField
        SupplyVoltageMagnitudeEditFieldLabel  matlab.ui.control.Label
        MotorParameterInputsPanel       matlab.ui.container.Panel
        MotorProfileDropDown            matlab.ui.control.DropDown
        MotorProfileDropDownLabel       matlab.ui.control.Label
        CoulombFrictionEditField        matlab.ui.control.NumericEditField
        CoulombFrictionEditFieldLabel   matlab.ui.control.Label
        InitialCurrentEditField         matlab.ui.control.NumericEditField
        InitialCurrentEditFieldLabel    matlab.ui.control.Label
        ViscousFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        ViscousFrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        RotorInertiaEditField           matlab.ui.control.NumericEditField
        RotorInertiaEditFieldLabel      matlab.ui.control.Label
        BackEMFConstantEditField        matlab.ui.control.NumericEditField
        BackEMFConstantEditFieldLabel   matlab.ui.control.Label
        TorqueconstantEditField         matlab.ui.control.NumericEditField
        TorqueconstantEditFieldLabel    matlab.ui.control.Label
        ArmatureInductanceEditField     matlab.ui.control.NumericEditField
        ArmatureInductanceEditFieldLabel  matlab.ui.control.Label
        InitialSpeedEditField           matlab.ui.control.NumericEditField
        InitialSpeedEditFieldLabel      matlab.ui.control.Label
        ArmatureResistanceEditField     matlab.ui.control.NumericEditField
        ArmatureResistanceEditFieldLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        ImportedTime
        ImportedVoltage
        ImportedLoad
        ImportedMeasuredTime
        ImportedMeasuredCurrent
        ImportedMeasuredSpeed
        IsRunning = false;        
        InitialPositionEditField        % created in startupFcn (Initial Position, rad)
        InitialPositionEditFieldLabel
        % Voltage timing for the Ramp and Pulse waveforms (s). Set by the
        % test-case presets (dc_motor_test_cases.csv: M2 ramp 0.5 s,
        % M3 pulse 0.2-1.5 s).
        RampDuration   = 0.5;
        PulseStartTime = 0.2;
        PulseEndTime   = 1.5;
        PlotsTab            % "Plots" tab with the Plot View drop-down (built in buildPlotLayout)
        PlotViewLabel
        PlotViewDropDown
        PlotViewPanels      % struct array: name + panel holding that view's axes
        DefaultView    % fresh-start look of axes, tabs, table and status labels (captured once in startupFcn, restored by Reset)

        % ----- Readable aliases for auto-named App Designer components -----
        % App Designer names components UIAxes, UIAxes_2, StatusLabel_3 and so
        % on. Those names stay in Design View, but bindReadableNames() copies
        % each handle into the self-explanatory property below, and the rest of
        % this file uses only these. A handle copy refers to the SAME object, so
        % there is no duplication - just a clearer name.
        ArmatureCurrentAxes    % UIAxes :              armature current i_a vs time
        AppliedVoltageAxes     % UIAxes_2 :            applied voltage V_a vs time (after the limiter)
        BackEmfAxes            % UIAxes_3 :            back-EMF e_b vs time
        MotorSpeedAxes         % UIAxes_4 :            angular speed omega vs time
        ShaftPositionAxes      % UIAxes_5 :            shaft position theta vs time
        TorqueAxes             % UIAxes_6 :            electromagnetic torque T_e and load torque T_L vs time
        MethodCompareAxes      % UIAxes_7 :            Euler and RK4 speed responses on the same axes
        ErrorVsTimeAxes        % UIAxes_8 :            absolute speed error vs time
        ErrorVsStepSizeAxes    % UIAxes_9 :            maximum speed error vs step size (log-log)
        RunStatusLabel         % StatusLabel_2 :       status text beside Run Simulation
        TimeStepStatusLabel    % StatusLabel_3 :       status text beside Time-Step Study
        CompareStatusLabel     % StatusLabel_4 :       status text beside Compare Methods
        ExportResultsButton    % ExportResultsButton_2 : Export Results button
        NumericalTableTab      % DataTable :           the "Numerical Table" tab
        ResultsTable           % UITable :             table of numerical results
        SimTimeLabel           % Time000sLabel :       elapsed-time readout on the animation
        MotorTempLabel         % TempLabel :           winding-temperature readout on the animation
    end
    
    methods (Access = public)
    function setRunningFalse(app)
        if isvalid(app)
            app.IsRunning = false;
        end
    end
    end

    % =====================================================================
    %  SIMULATION ENGINE
    %  Pure computation - no reference to the app or any UI component, so
    %  these can be called straight from the command window for testing.
    % =====================================================================
    methods (Static, Access = public)

        function simData = simulateDCMotor(p, Va_func, TL_func, method)
            % Integrate the motor equations over the requested time span.
            % Inputs : p        - struct of motor and simulation parameters
            %          Va_func  - function handle, applied voltage      Va(t)
            %          TL_func  - function handle, load torque          TL(t, omega)
            %          method   - 'Euler' | 'RK4' | 'RK45 (Adaptive)' | 'Both'
            % Output : simData  - struct with t, ia, w, theta, Te, eb, Va, TL, MotorTemp
            %                     and (for 'Both') the separate Euler and RK4 traces.
            % The solvers only ever see motorDerivative(), so every method integrates
            % exactly the same model.
            % Build a time vector that ALWAYS ends exactly at t_end.
            t = DCMotorSimulator.makeTimeVector(p.t_end, p.h);
            N = numel(t);
            x0 = [p.ia0; p.w0; p.th0; p.Tamb];

            runEuler = strcmp(method,'Euler') || strcmp(method,'Both');
            runRK4   = strcmp(method,'RK4')   || strcmp(method,'Both');

            simData = struct();
            simData.t = t;
            simData.method = method;
            simData.unstable = false;

            % ---------------- Forward Euler ----------------
            if runEuler
                x_euler = zeros(4,N);
                x_euler(:,1) = x0;
                for n = 1:N-1
                    h_n = t(n+1) - t(n);
                    tn  = t(n);
                    dx  = DCMotorSimulator.motorDerivative(p, Va_func(tn), TL_func, tn, x_euler(:,n));
                    x_euler(:,n+1) = x_euler(:,n) + h_n*dx;
                    if any(~isfinite(x_euler(:,n+1)))
                        simData.unstable = true;
                        break;
                    end
                end
                simData.euler_x     = x_euler;
                simData.euler_ia    = x_euler(1,:);
                simData.euler_w     = x_euler(2,:);
                simData.euler_theta = x_euler(3,:);
                simData.euler_T     = x_euler(4,:);
            end

            % ---------------- Classical RK4 ----------------
            % Run RK4 even if Euler diverged, so 'Both' still has data.
            if runRK4
                x_rk4 = zeros(4,N);
                x_rk4(:,1) = x0;
                for n = 1:N-1
                    h_n = t(n+1) - t(n);
                    tn  = t(n);
                    tm  = tn + h_n/2;
                    tn1 = t(n+1);

                    k1 = DCMotorSimulator.motorDerivative(p, Va_func(tn),  TL_func, tn,  x_rk4(:,n));
                    k2 = DCMotorSimulator.motorDerivative(p, Va_func(tm),  TL_func, tm,  x_rk4(:,n) + (h_n/2)*k1);
                    k3 = DCMotorSimulator.motorDerivative(p, Va_func(tm),  TL_func, tm,  x_rk4(:,n) + (h_n/2)*k2);
                    % k4 is the slope at the END of [tn, tn1): evaluate the inputs
                    % just inside the interval (left limit) so a step in Va or TL
                    % at tn1 does not leak into this step (keeps RK4 4th order).
                    tn1_in = tn1 - 1e-9*h_n;
                    k4 = DCMotorSimulator.motorDerivative(p, Va_func(tn1_in), TL_func, tn1_in, x_rk4(:,n) + h_n*k3);

                    x_rk4(:,n+1) = x_rk4(:,n) + (h_n/6)*(k1 + 2*k2 + 2*k3 + k4);

                    if any(~isfinite(x_rk4(:,n+1)))
                        simData.unstable = true;
                        break;
                    end
                end
                simData.rk4_x     = x_rk4;
                simData.rk4_ia    = x_rk4(1,:);
                simData.rk4_w     = x_rk4(2,:);
                simData.rk4_theta = x_rk4(3,:);
                simData.rk4_T     = x_rk4(4,:);
            end

            % ---------------- Adaptive RK45 ----------------
            if strcmp(method,'RK45 (Adaptive)')
                [t45, x45, rk45_ok] = DCMotorSimulator.runAdaptiveRK45(p, Va_func, TL_func, x0);
                simData.t = t45;
                simData.rk45_x     = x45;
                simData.rk45_ia    = x45(1,:);
                simData.rk45_w     = x45(2,:);
                simData.rk45_theta = x45(3,:);
                simData.rk45_T     = x45(4,:);
                if ~rk45_ok
                    simData.unstable = true;
                end
            end

            % ---------------- Pick the primary result ----------------
            x_primary = [];
            switch method
                case 'Euler'
                    if isfield(simData,'euler_x'), x_primary = simData.euler_x; end
                case {'RK4','Both'}
                    if isfield(simData,'rk4_x'),   x_primary = simData.rk4_x;   end
                case 'RK45 (Adaptive)'
                    if isfield(simData,'rk45_x'),  x_primary = simData.rk45_x;  end
            end

            % If nothing usable exists, flag as unstable and return early so
            % the GUI can show its alert instead of crashing on a missing field.
            if isempty(x_primary)
                simData.unstable = true;
                simData.ia = []; simData.w = []; simData.theta = []; simData.MotorTemp = [];
                simData.Va = []; simData.TL = []; simData.Te = []; simData.eb = [];
                return;
            end

            simData.ia        = x_primary(1,:);
            simData.w         = x_primary(2,:);
            simData.theta     = x_primary(3,:);
            simData.MotorTemp = x_primary(4,:);

            % The waveform handles are vectorised, so call them directly.
            % Applied voltage as seen by the motor: include the current limiter,
            % using the same effective resistance as motorDerivative.
            Va_cmd = Va_func(simData.t);
            if p.useLimit
                if p.useThermal
                    Ra_eff = max(p.Ra*(1 + p.alpha*(simData.MotorTemp - p.Tamb)), 0.01*p.Ra);
                else
                    Ra_eff = p.Ra;
                end
                Va_cmd = min(max(Va_cmd, -Ra_eff.*p.ILimit + p.Ke.*simData.w), Ra_eff.*p.ILimit + p.Ke.*simData.w);
            end
            simData.Va = Va_cmd;
            simData.TL = TL_func(simData.t, simData.w);
            simData.TL_func = TL_func;   % used to draw the load characteristic
            simData.Te = p.Kt .* simData.ia;
            simData.eb = p.Ke .* simData.w;

            % Compatibility aliases so updatePlots can read either naming style.
            if isfield(simData,'euler_ia')
                simData.ia_euler = simData.euler_ia;
                simData.w_euler  = simData.euler_w;
            end

            % Final physical sanity checks (primary AND Euler in 'Both' mode).
            if any(~isfinite(x_primary(:))) || any(abs(simData.w) > 1e6) || any(abs(simData.ia) > 1e9)
                simData.unstable = true;
            end
            if isfield(simData,'euler_x')
                if any(~isfinite(simData.euler_x(:))) || any(abs(simData.euler_w) > 1e6)
                    simData.unstable = true;
                end
            end
        end

        function [t_out, y_out, ok] = validateImportedWaveform(t_in, y_in)
            % Check a waveform read from a CSV before it is used.
            % Rejects empty data, mismatched column lengths, non-finite values and
            % time vectors that are not strictly increasing (interp1 needs that).
            ok = false;
            t_out = [];
            y_out = [];
            if isempty(t_in) || isempty(y_in)
                return;
            end
            t_in = t_in(:);
            y_in = y_in(:);
            if numel(t_in) ~= numel(y_in) || numel(t_in) < 2
                return;
            end
            finiteRows = isfinite(t_in) & isfinite(y_in);
            t_in = t_in(finiteRows);
            y_in = y_in(finiteRows);
            if numel(t_in) < 2
                return;
            end
            [t_out, order] = sort(t_in);
            y_out = y_in(order);
            if any(diff(t_out) <= 0)
                ok = false;
                return;
            end
            ok = true;
        end

        function dx = motorDerivative(p, Va_unlimited, TL_func, t, x)
            % THE MODEL. Return dx/dt for the present state x.
            % x = [ia; omega; theta; T_winding].  Called by every solver, at every
            % stage, so all the physics lives here and nowhere else:
            %   - optional thermal model  : winding temperature changes Ra
            %   - optional current limit  : clamps Va around the back-EMF
            %   - optional Coulomb friction
            %   - the four state equations themselves
            % TL_func(t, w): load torque as a function of time AND speed
            % (speed-dependent load characteristics, switched on at t_load).
            ia = x(1);
            w  = x(2);
            TL = TL_func(t, w);

            if numel(x) >= 4
                T = x(4);
            else
                T = p.Tamb;
            end

            % Temperature-dependent armature resistance.
            if p.useThermal
                Ra_eff = p.Ra * (1 + p.alpha*(T - p.Tamb));
                Ra_eff = max(Ra_eff, 0.01*p.Ra);   % keep it physical
            else
                Ra_eff = p.Ra;
            end

            % Current limiter: clamp the applied voltage around the back-EMF
            % using the SAME effective resistance as the electrical model.
            if p.useLimit
                Vmax = Ra_eff*p.ILimit + p.Ke*w;
                Vmin = -Ra_eff*p.ILimit + p.Ke*w;
                Va = min(max(Va_unlimited, Vmin), Vmax);
            else
                Va = Va_unlimited;
            end

            % Optional Coulomb friction (smoothed sign).
            if p.EnableCoulomb
                T_coulomb = p.Tc*tanh(w/0.01);
            else
                T_coulomb = 0;
            end

            dia    = (Va - Ra_eff*ia - p.Ke*w) / p.La;                 % Va = La*dia/dt + Ra*ia + Ke*w
            dw     = (p.Kt*ia - p.B*w - TL - T_coulomb) / p.J;         % J*dw/dt = Kt*ia - B*w - TL - Tf
            dtheta = w;

            if p.useThermal
                dT = (ia^2*Ra_eff - (T - p.Tamb)/p.Rth) / p.Cth;
            else
                dT = 0;
            end

            dx = [dia; dw; dtheta; dT];
        end

        function [t_out, x_out, ok] = runAdaptiveRK45(p, Va_func, TL_func, x0)
            % Adaptive-step Runge-Kutta-Fehlberg 4(5) solver.
            % Six slope evaluations give both a 4th- and a 5th-order estimate of the
            % step; their difference estimates the local error. If the error is too
            % large the step is rejected and retried smaller, otherwise it is accepted
            % and the step grows. Returns ok = false if it cannot converge.
            t = 0;
            x = x0;

            h = min(p.h, p.t_end/100);
            h = max(h, p.t_end/1e6);

            h_min = max(p.t_end*1e-10, eps);
            % Cap the step so a 50 Hz waveform cannot be stepped over.
            h_max = min(max(10*p.h, 1e-4), 0.02);

            maxAcceptedSteps = 5e6;
            maxRejectedSteps = 1000;
            accepted = 0;
            rejected = 0;

            t_out = zeros(1, 1000);
            x_out = zeros(4, 1000);
            t_out(1)   = t;
            x_out(:,1) = x;
            outIndex = 1;
            ok = true;

            while t < p.t_end
                if accepted >= maxAcceptedSteps
                    ok = false;
                    break;
                end

                h = min(h, p.t_end - t);

                % Fehlberg embedded 4(5) pair.
                k1 = DCMotorSimulator.motorDerivative(p, Va_func(t),          TL_func, t,          x);
                k2 = DCMotorSimulator.motorDerivative(p, Va_func(t+h/4),      TL_func, t+h/4,      x + h*(k1/4));
                k3 = DCMotorSimulator.motorDerivative(p, Va_func(t+3*h/8),    TL_func, t+3*h/8,    x + h*(3*k1/32 + 9*k2/32));
                k4 = DCMotorSimulator.motorDerivative(p, Va_func(t+12*h/13),  TL_func, t+12*h/13,  x + h*(1932*k1/2197 - 7200*k2/2197 + 7296*k3/2197));
                t_end_in = t + h - 1e-9*h;   % left limit at the end of the step
                k5 = DCMotorSimulator.motorDerivative(p, Va_func(t_end_in),   TL_func, t_end_in,        x + h*(439*k1/216 - 8*k2 + 3680*k3/513 - 845*k4/4104));
                k6 = DCMotorSimulator.motorDerivative(p, Va_func(t+h/2),      TL_func, t+h/2,      x + h*(-8*k1/27 + 2*k2 - 3544*k3/2565 + 1859*k4/4104 - 11*k5/40));

                x4 = x + h*(25*k1/216 + 1408*k3/2565 + 2197*k4/4104 - k5/5);
                x5 = x + h*(16*k1/135 + 6656*k3/12825 + 28561*k4/56430 - 9*k5/50 + 2*k6/55);

                % Per-component scaling: current, speed, angle and temperature
                % have different units/magnitudes, so each gets its own tolerance.
                scale   = p.AbsTol + p.RelTol*max(abs(x), abs(x5));
                errNorm = max(abs(x5 - x4) ./ max(scale, eps));

                if ~isfinite(errNorm)
                    ok = false;
                    break;
                end

                if errNorm <= 1
                    % ---- accepted step ----
                    t = t + h;
                    x = x5;
                    accepted = accepted + 1;
                    rejected = 0;

                    outIndex = outIndex + 1;
                    if outIndex > size(t_out,2)
                        newSize = size(t_out,2)*2;
                        t_out(1, end+1:newSize)   = 0;
                        x_out(:, end+1:newSize)   = 0;
                    end
                    t_out(outIndex)   = t;
                    x_out(:,outIndex) = x;

                    if errNorm == 0
                        factor = 2.0;
                    else
                        factor = min(2.0, max(0.2, 0.9*(1/errNorm)^(1/5)));
                    end
                    h = min(h_max, max(h_min, h*factor));
                else
                    % ---- rejected step ----
                    rejected = rejected + 1;
                    if rejected > maxRejectedSteps
                        ok = false;
                        break;
                    end
                    factor = min(0.5, max(0.1, 0.9*(1/max(errNorm,eps))^(1/5)));
                    if h <= h_min*(1 + 1e-12)
                        ok = false;
                        break;
                    end
                    h = max(h_min, h*factor);
                end

                if any(~isfinite(x)) || abs(x(1)) > 1e9 || abs(x(2)) > 1e9
                    ok = false;
                    break;
                end
            end

            t_out = t_out(1:outIndex);
            x_out = x_out(:,1:outIndex);
            if isempty(t_out)
                ok = false;
            end
        end

        function t = makeTimeVector(t_end, h)
            % Build a uniform time grid 0 : h : t_end.
            % The final point is forced to land exactly on t_end, so the last step may
            % be slightly shorter than h.
            if t_end <= 0 || h <= 0
                error('Simulation duration and time step must be positive.');
            end
            n = floor(t_end/h);
            t = (0:n)*h;
            if isempty(t) || abs(t(end) - t_end) > 100*eps(max(1,t_end))
                t = [t, t_end];      % add a shorter final step
            else
                t(end) = t_end;
            end
            t = unique(t, 'stable');
        end

        function x = runEulerFixedStep(p, Va_func, TL_func, t)
            % Forward Euler: x(n+1) = x(n) + h * f(t(n), x(n)).
            % One derivative evaluation per step. Global error is O(h) - first order.
            N = numel(t);
            x = zeros(4,N);
            x(:,1) = [p.ia0; p.w0; p.th0; p.Tamb];
            for n = 1:N-1
                h  = t(n+1) - t(n);
                dx = DCMotorSimulator.motorDerivative(p, Va_func(t(n)), TL_func, t(n), x(:,n));
                x(:,n+1) = x(:,n) + h*dx;
                if any(~isfinite(x(:,n+1)))
                    x(:,n+2:end) = NaN;     % mark the rest as invalid
                    break;
                end
            end
        end

        function x = runRK4FixedStep(p, Va_func, TL_func, t)
            % Classical fourth-order Runge-Kutta.
            % Four derivative evaluations per step, combined with weights 1:2:2:1.
            % Global error is O(h^4), so halving h cuts the error by about 16 times.
            N = numel(t);
            x = zeros(4,N);
            x(:,1) = [p.ia0; p.w0; p.th0; p.Tamb];
            for n = 1:N-1
                h   = t(n+1) - t(n);
                tn  = t(n);
                tm  = tn + h/2;
                tn1 = t(n+1);

                k1 = DCMotorSimulator.motorDerivative(p, Va_func(tn),  TL_func, tn,  x(:,n));
                k2 = DCMotorSimulator.motorDerivative(p, Va_func(tm),  TL_func, tm,  x(:,n) + (h/2)*k1);
                k3 = DCMotorSimulator.motorDerivative(p, Va_func(tm),  TL_func, tm,  x(:,n) + (h/2)*k2);
                tn1_in = tn1 - 1e-9*h;   % left limit (see simulateDCMotor)
                k4 = DCMotorSimulator.motorDerivative(p, Va_func(tn1_in), TL_func, tn1_in, x(:,n) + h*k3);

                x(:,n+1) = x(:,n) + (h/6)*(k1 + 2*k2 + 2*k3 + k4);
                if any(~isfinite(x(:,n+1)))
                    x(:,n+2:end) = NaN;
                    break;
                end
            end
        end
    end


    % =====================================================================
    %  INTERNAL HELPERS
    %  Read the interface, build the input waveforms, draw the output.
    % =====================================================================
    methods(Access = private)

        function setMotorParams(app, Ra, La, Kt, Ke, J, B)
            app.ArmatureResistanceEditField.Value        = Ra;
            app.ArmatureInductanceEditField.Value        = La;
            app.TorqueconstantEditField.Value            = Kt;
            app.BackEMFConstantEditField.Value           = Ke;
            app.RotorInertiaEditField.Value              = J;
            app.ViscousFrictionCoefficientEditField.Value = B;
        end

        function p = getSimParams(app)
            % Read every input box into the parameter struct p.
            % One place where the UI is converted into plain numbers, so the solver
            % code never has to know anything about the interface.
            p.Ra  = app.ArmatureResistanceEditField.Value;
            p.La  = app.ArmatureInductanceEditField.Value;
            p.Kt  = app.TorqueconstantEditField.Value;
            p.Ke  = app.BackEMFConstantEditField.Value;
            p.J   = app.RotorInertiaEditField.Value;
            p.B   = app.ViscousFrictionCoefficientEditField.Value;
            p.Tc  = app.CoulombFrictionEditField.Value;
            p.ia0 = app.InitialCurrentEditField.Value;
            p.w0  = app.InitialSpeedEditField.Value;
            p.th0 = 0;
            if ~isempty(app.InitialPositionEditField) && isvalid(app.InitialPositionEditField)
                p.th0 = app.InitialPositionEditField.Value;
            end

            p.V_mag   = app.SupplyVoltageMagnitudeEditField.Value;
            p.TL_mag  = app.LoadTorqueMagnitudeEditField.Value;
            p.TL_time = app.LoadApplicationTimeEditField.Value;
            p.t_end   = app.SimulationDurationEditField.Value;
            p.h       = app.TimeStepEditField.Value;

            p.useLimit = logical(app.EnableCurrentLimitCheckBox.Value);
            p.ILimit   = app.MaxCurrentAEditField.Value;

            p.useThermal    = logical(app.EnableThermalModelCheckBox.Value);
            p.EnableCoulomb = logical(app.EnableCoulombFrictionCheckBox.Value);

            p.RelTol = 1e-4;
            p.AbsTol = 1e-4;

            p.Tamb  = 25.0;
            p.alpha = 0.00393;
            p.Rth   = 2.0;
            p.Cth   = 10.0;
        end

        function TL_func = getTorqueFunction(app, p)
            % Build the load-torque handle TL(t, omega).
            % TL = u(t - t_load) * g(omega): the load is switched on at the Load
            % Application Time, and from then on follows its own torque-speed
            % characteristic g. A real load's torque depends on how fast it is driven,
            % which is why omega is an argument and not just t.
            % Load torque TL(t, w) = u(t - t_load) * f(w): the load is switched
            % on at the Load Application Time, and its size then follows the
            % load's torque-speed characteristic f(w).
            % "Load-Torque Magnitude" is the torque at the reference speed
            % w_ref = V/Ke (the motor's no-load speed at the supply voltage).
            w_ref = max(abs(p.V_mag) / max(p.Ke, eps), eps);
            Tm    = p.TL_mag;
            tOn   = p.TL_time;
            on    = @(t) double(t >= tOn);
            dirn  = @(w) tanh(w ./ (1e-3*w_ref));   % smooth sign: passive loads oppose rotation

            profile = app.LoadTorqueProfileDropDown.Value;
            switch profile
                case 'Constant (T = K)'
                    % hoists, cranes, conveyors: same torque at every speed
                    TL_func = @(t, w) on(t) .* Tm .* ones(size(w));

                case 'Linear (T ∝ ω)'
                    % generator / eddy-current brake: T = K*w
                    TL_func = @(t, w) on(t) .* Tm .* (w ./ w_ref);

                case 'Quadratic (T ∝ ω²)'
                    % fans, blowers, centrifugal pumps: T = K*w^2 (opposing rotation)
                    TL_func = @(t, w) on(t) .* Tm .* (w .* abs(w)) ./ w_ref^2;

                case 'Constant Power (T ∝ 1/ω)'
                    % winders: T = P/w with P = Tm*w_ref; limited to 10*Tm
                    % below 10 % of w_ref so it stays finite at standstill
                    TL_func = @(t, w) on(t) .* Tm .* min(w_ref ./ max(abs(w), eps), 10) .* dirn(w);

                case 'Imported File'
                    if isempty(app.ImportedTime) || isempty(app.ImportedLoad)
                        TL_func = @(t, w) zeros(size(t));
                        return;
                    end
                    [t_load, load_imp, ok_l] = DCMotorSimulator.validateImportedWaveform(app.ImportedTime, app.ImportedLoad);
                    if ~ok_l
                        TL_func = @(t, w) zeros(size(t));
                        return;
                    end
                    % measured load profile: a function of time only
                    TL_func = @(t, w) interp1(t_load, load_imp, t, 'linear', 'extrap');

                otherwise
                    TL_func = @(t, w) zeros(size(t));
            end
        end

        function [Va_func, success] = getVoltageFunction(app, V_mag, ~)
            % Build the applied-voltage handle Va(t) for the selected waveform.
            % Returns success = false (and a zero waveform) if the inputs are invalid,
            % so the caller can abort cleanly.
            success = true;
            waveform_type = app.VoltageWaveformDropDown.Value;

            switch waveform_type
                case 'Step'
                    Va_func = @(t) V_mag .* ones(size(t));

                case 'Ramp'
                    % Rise from 0 to V_mag over RampDuration, then hold V_mag.
                    Tr = app.RampDuration;
                    if Tr > 0
                        Va_func = @(t) V_mag .* min(max(t ./ Tr, 0), 1);
                    else
                        Va_func = @(t) V_mag .* ones(size(t));   % zero ramp time = step
                    end

                case 'Pulse'
                    % V_mag between PulseStartTime and PulseEndTime, 0 otherwise.
                    ts = app.PulseStartTime;
                    te = app.PulseEndTime;
                    if te <= ts
                        uialert(app.UIFigure, 'Pulse end time must be greater than pulse start time.', 'Invalid Input');
                        success = false;
                        Va_func = @(t) zeros(size(t));
                        return;
                    end
                    Va_func = @(t) V_mag .* (t >= ts & t < te);

                case 'Imported File'
                    if isempty(app.ImportedTime) || isempty(app.ImportedVoltage)
                        uialert(app.UIFigure, 'No imported voltage waveform is loaded. Import a CSV first.', 'Missing Data');
                        success = false;
                        Va_func = @(t) zeros(size(t));
                        return;
                    end
                    [t_imp, v_imp, ok] = DCMotorSimulator.validateImportedWaveform(app.ImportedTime, app.ImportedVoltage);
                    if ~ok
                        uialert(app.UIFigure, 'Imported waveform contains invalid or insufficient data.', 'Invalid Imported Waveform');
                        success = false;
                        Va_func = @(t) zeros(size(t));
                        return;
                    end
                    Va_func = @(t) interp1(t_imp, v_imp, min(max(t,t_imp(1)),t_imp(end)), 'linear');

                case 'User-defined'
                    waveChoice = questdlg('Select Waveform Type:', 'Custom Waveform', 'PULSE', 'SINE', 'PULSE');
                    if isempty(waveChoice)
                        success = false;
                        Va_func = @(t) zeros(size(t));
                        return;
                    end

                    if strcmp(waveChoice, 'PULSE')
                        prompt = {'Initial Voltage (V1):', 'Peak Voltage (V2):', 'Delay Time (s):', 'Rise Time (s):', 'Fall Time (s):', 'Pulse Width (s):', 'Period (s):'};
                        definput = {'0', num2str(V_mag), '1', '0.1', '0.1', '2', '5'};
                        ans_cell = inputdlg(prompt, 'PULSE Parameters', [1 50], definput);
                        if isempty(ans_cell)
                            success = false;
                            Va_func = @(t) zeros(size(t));
                            return;
                        end
                        v1  = app.parseValue(ans_cell{1});
                        v2  = app.parseValue(ans_cell{2});
                        td  = app.parseValue(ans_cell{3});
                        tr  = max(app.parseValue(ans_cell{4}), 1e-9);
                        tf  = max(app.parseValue(ans_cell{5}), 1e-9);
                        pw  = max(app.parseValue(ans_cell{6}), 0);
                        per = app.parseValue(ans_cell{7});

                        if per <= 0
                            uialert(app.UIFigure, 'Period must be greater than zero.', 'Invalid Input');
                            success = false;
                            Va_func = @(t) zeros(size(t));
                            return;
                        end
                        pulse_val = @(tm) (tm < td) .* v1 ...
                            + (tm >= td & tm < td+tr) .* (v1 + (v2-v1).*(tm-td)./tr) ...
                            + (tm >= td+tr & tm < td+tr+pw) .* v2 ...
                            + (tm >= td+tr+pw & tm < td+tr+pw+tf) .* (v2 - (v2-v1).*(tm-(td+tr+pw))./tf) ...
                            + (tm >= td+tr+pw+tf) .* v1;
                        Va_func = @(t) pulse_val(mod(t, per));

                    elseif strcmp(waveChoice, 'SINE')
                        % Default: DC supply with 10 % ripple at 100 Hz (full-wave
                        % rectified 50 Hz mains), i.e. a realistic DC-motor input.
                        prompt = {'DC Offset (V):', 'Ripple Amplitude (V):', 'Ripple Frequency (Hz):', 'Delay (s):', 'Damping Factor:'};
                        definput = {num2str(V_mag), num2str(0.1*V_mag), '100', '0', '0'};
                        ans_cell = inputdlg(prompt, 'SINE Parameters', [1 50], definput);
                        if isempty(ans_cell)
                            success = false;
                            Va_func = @(t) zeros(size(t));
                            return;
                        end
                        vo    = app.parseValue(ans_cell{1});
                        va    = app.parseValue(ans_cell{2});
                        freq  = app.parseValue(ans_cell{3});
                        td    = app.parseValue(ans_cell{4});
                        theta = app.parseValue(ans_cell{5});

                        if freq < 0 || theta < 0
                            uialert(app.UIFigure, 'Frequency and damping factor must be nonnegative.', 'Invalid Input');
                            success = false;
                            Va_func = @(t) zeros(size(t));
                            return;
                        end
                        Va_func = @(t) (t < td) .* vo + (t >= td) .* (vo + va .* exp(-theta.*(t-td)) .* sin(2*pi*freq.*(t-td)));
                    else
                        success = false;
                        Va_func = @(t) zeros(size(t));
                    end

                otherwise
                    uialert(app.UIFigure, ['Unsupported voltage waveform: ', waveform_type], 'Invalid Waveform');
                    success = false;
                    Va_func = @(t) zeros(size(t));
            end
        end

        function bindReadableNames(app)
            % Point every readable alias at its App Designer component.
            % Called first in startupFcn, before anything else touches the UI.
            app.ArmatureCurrentAxes    = app.UIAxes;
            app.AppliedVoltageAxes     = app.UIAxes_2;
            app.BackEmfAxes            = app.UIAxes_3;
            app.MotorSpeedAxes         = app.UIAxes_4;
            app.ShaftPositionAxes      = app.UIAxes_5;
            app.TorqueAxes             = app.UIAxes_6;
            app.MethodCompareAxes      = app.UIAxes_7;
            app.ErrorVsTimeAxes        = app.UIAxes_8;
            app.ErrorVsStepSizeAxes    = app.UIAxes_9;
            app.RunStatusLabel         = app.StatusLabel_2;
            app.TimeStepStatusLabel    = app.StatusLabel_3;
            app.CompareStatusLabel     = app.StatusLabel_4;
            app.ExportResultsButton    = app.ExportResultsButton_2;
            app.NumericalTableTab      = app.DataTable;
            app.ResultsTable           = app.UITable;
            app.SimTimeLabel           = app.Time000sLabel;
            app.MotorTempLabel         = app.TempLabel;
        end

        function buildPlotLayout(app)
            % Tab layout: Animation | Plots | Numerical Table (Animation first
            % and selected). The Plots tab has a "Plot View" drop-down that
            % switches between groups of the existing axes, so all plotting
            % code is unchanged. Built once at launch; Design View still shows
            % the original tabs, whose axes are moved here at run time.
            % Everything is laid out with equal margins so each page is
            % symmetrical and the plots fill the page.
            if ~isempty(app.PlotViewDropDown) && isvalid(app.PlotViewDropDown)
                return;
            end
            tg = app.TabGroup;

            % Usable size inside a tab (measured; fallback if not yet drawn).
            drawnow;
            tabSize = app.AnimationTab.Position(3:4);
            if any(tabSize < 100)
                tabSize = [tg.Position(3), tg.Position(4) - 25];
            end
            W = tabSize(1);
            H = tabSize(2);
            m = 10;                               % margin / gap used everywhere

            app.PlotsTab = uitab(tg, 'Title', 'Plots');

            % ---- Plot View selector: centred row at the top ----
            rowH = 22; lblW = 70; ddW = 190; gapLD = 8;
            x0 = round((W - (lblW + gapLD + ddW)) / 2);
            rowY = H - m - rowH;

            app.PlotViewLabel = uilabel(app.PlotsTab);
            app.PlotViewLabel.Text = 'Plot View';
            app.PlotViewLabel.FontSize = 14;
            app.PlotViewLabel.HorizontalAlignment = 'right';
            app.PlotViewLabel.Position = [x0 rowY lblW rowH];

            app.PlotViewDropDown = uidropdown(app.PlotsTab);
            app.PlotViewDropDown.Items = {'Torque-Speed', 'Electrical', 'Mechanical', 'Numerical Analysis'};
            app.PlotViewDropDown.Value = 'Torque-Speed';
            app.PlotViewDropDown.FontSize = 14;
            app.PlotViewDropDown.Position = [x0 + lblW + gapLD, rowY, ddW, rowH];
            app.PlotViewDropDown.ValueChangedFcn = @(src, ~) app.showPlotView(src.Value);

            views = { ...
                'Torque-Speed',       {app.TorqueSpeedAxes}; ...
                'Electrical',         {app.UIAxes,   app.UIAxes_2, app.UIAxes_3}; ...   % current, voltage, back-EMF
                'Mechanical',         {app.UIAxes_4, app.UIAxes_5, app.UIAxes_6}; ...   % speed, position, torques
                'Numerical Analysis', {app.UIAxes_7, app.UIAxes_8, app.UIAxes_9}};      % Euler vs RK4, error vs t, error vs h

            % ---- One panel per view, filling the space below the selector ----
            panelW = W;
            panelH = rowY - m;                    % same gap above and below the row
            app.PlotViewPanels = struct('name', {}, 'panel', {});
            for k = 1:size(views, 1)
                pnl = uipanel(app.PlotsTab);
                pnl.BorderType = 'none';
                pnl.BackgroundColor = app.PlotsTab.BackgroundColor;
                pnl.Position = [0 0 panelW panelH];
                pnl.Visible = 'off';

                axs = views{k, 2};
                n   = numel(axs);
                axW = panelW - 2*m;               % equal left/right margins
                axH = (panelH - (n + 1)*m) / n;   % equal heights, equal gaps, fills the page
                for i = 1:n
                    axs{i}.Parent   = pnl;
                    axs{i}.Position = [m, m + (n - i)*(axH + m), axW, axH];
                end
                app.PlotViewPanels(k).name  = views{k, 1};
                app.PlotViewPanels(k).panel = pnl;
            end

            % The old per-group tabs are now empty.
            delete(app.ElectricalTab);
            delete(app.MechanicalTab);
            delete(app.AnalysisTab);

            % ---- Animation page: labels mirrored, motor fills the rest ----
            lblTW = app.Time000sLabel.Position(3);
            lblTH = app.Time000sLabel.Position(4);
            lblY  = H - 3*m - lblTH;
            sideM = 5*m;
            app.Time000sLabel.Position = [sideM, lblY, lblTW, lblTH];
            app.TempLabel.Position     = [W - sideM - app.TempLabel.Position(3), lblY, app.TempLabel.Position(3), lblTH];
            app.Time000sLabel.HorizontalAlignment = 'left';
            app.TempLabel.HorizontalAlignment = 'right';
            app.AnimationAxes.Position = [m, m, W - 2*m, lblY - 2*m];

            % ---- Numerical table fills its page ----
            app.DataTable.Title = 'Numerical Table';
            app.UITable.Position = [m, m, W - 2*m, H - 2*m];

            tg.Children = [app.AnimationTab; app.PlotsTab; app.DataTable];
            tg.SelectedTab = app.AnimationTab;
            app.showPlotView('Torque-Speed');
        end

        function showPlotView(app, name)
            % Show one group of plots on the Plots tab.
            if isempty(app.PlotViewPanels)
                return;
            end
            for k = 1:numel(app.PlotViewPanels)
                app.PlotViewPanels(k).panel.Visible = strcmp(app.PlotViewPanels(k).name, name);
            end
            if ~strcmp(app.PlotViewDropDown.Value, name)
                app.PlotViewDropDown.Value = name;
            end
        end

        function captureOrRestoreDefaultView(app, mode)
            % 'capture': remember the fresh-start state of every axes, the
            % selected tab, table columns and status-label colours (done once,
            % at launch). 'restore': put all of it back (used by Reset), so a
            % reset app looks exactly like a freshly opened one.
            ax = {app.AnimationAxes, app.TorqueSpeedAxes, app.UIAxes, app.UIAxes_2, app.UIAxes_3, ...
                  app.UIAxes_4, app.UIAxes_5, app.UIAxes_6, app.UIAxes_7, app.UIAxes_8, app.UIAxes_9};
            props = {'XLimMode', 'YLimMode', 'ZLimMode', 'XScale', 'YScale', 'XGrid', 'YGrid', ...
                     'Color', 'View', 'DataAspectRatioMode', 'PlotBoxAspectRatioMode', 'Visible'};
            status = {app.StatusLabel_2, app.StatusLabel_3, app.StatusLabel_4};

            if strcmp(mode, 'capture')
                if ~isempty(app.DefaultView)
                    return;
                end
                v = struct();
                v.props  = cell(numel(ax), numel(props));
                v.labels = cell(numel(ax), 3);
                for k = 1:numel(ax)
                    v.props(k,:)  = get(ax{k}, props);
                    v.labels(k,:) = {ax{k}.Title.String, ax{k}.XLabel.String, ax{k}.YLabel.String};
                end
                v.statusColor  = cellfun(@(s) s.FontColor, status, 'UniformOutput', false);
                v.tab          = app.TabGroup.SelectedTab;
                v.tableColumns = app.UITable.ColumnName;
                v.plotView     = app.PlotViewDropDown.Value;
                app.DefaultView = v;
            else
                v = app.DefaultView;
                if isempty(v)
                    return;
                end
                for k = 1:numel(ax)
                    cla(ax{k});
                    legend(ax{k}, 'off');
                    set(ax{k}, props, v.props(k,:));
                    title(ax{k},  v.labels{k,1});
                    xlabel(ax{k}, v.labels{k,2});
                    ylabel(ax{k}, v.labels{k,3});
                end
                for k = 1:numel(status)
                    status{k}.FontColor = v.statusColor{k};
                end
                if ~isempty(v.tab) && isvalid(v.tab)
                    app.TabGroup.SelectedTab = v.tab;
                end
                app.UITable.Data = [];
                app.UITable.ColumnName = v.tableColumns;
                app.showPlotView(v.plotView);
            end
        end

        function applyTestCase(app, caseID)
            % Default test cases from dc_motor_test_cases.csv, with motor data
            % from dc_motor_parameters.csv (initial current, speed and
            % position are all 0).
            V = []; TL = []; tTL = [];
            switch caseID
                case 'M1'   % M1_Step_Voltage
                    motor = 'Motor A'; V = 24; TL = 0.05; tTL = 1;   tEnd = 3;   h = 1e-4;
                    wf = 'Step';  loadProfile = 'Constant (T = K)';
                case 'M2'   % M2_Ramp_Voltage
                    motor = 'Motor A'; V = 18; TL = 0.08; tTL = 1.2; tEnd = 3;   h = 2e-4;
                    wf = 'Ramp';  loadProfile = 'Constant (T = K)';
                    app.RampDuration = 0.5;
                case 'M3'   % M3_Pulse_Voltage
                    motor = 'Motor B'; V = 24; TL = 0.03; tTL = 0.8; tEnd = 2.5; h = 2e-4;
                    wf = 'Pulse'; loadProfile = 'Constant (T = K)';
                    app.PulseStartTime = 0.2;
                    app.PulseEndTime   = 1.5;
                case 'M4'   % M4_User_Defined_Profile (voltage and load from the CSV)
                    motor = 'Motor A'; tEnd = 3; h = 1e-3;
                    wf = 'Imported File'; loadProfile = 'Imported File';
                    if ~isempty(app.ImportedTime) && app.ImportedTime(end) > 0
                        tEnd = app.ImportedTime(end);   % run over the whole profile
                    end
                otherwise
                    return;
            end

            app.MotorProfileDropDown.Value = motor;
            app.MotorProfileDropDownValueChanged([]);   % loads Motor A / Motor B data

            app.InitialCurrentEditField.Value = 0;
            app.InitialSpeedEditField.Value   = 0;
            if ~isempty(app.InitialPositionEditField) && isvalid(app.InitialPositionEditField)
                app.InitialPositionEditField.Value = 0;
            end

            if ~isempty(V)
                app.SupplyVoltageMagnitudeEditField.Value = V;
                app.LoadTorqueMagnitudeEditField.Value    = TL;
                app.LoadApplicationTimeEditField.Value    = tTL;
            end
            app.SimulationDurationEditField.Value = tEnd;
            app.TimeStepEditField.Value           = h;

            app.VoltageWaveformDropDown.Value   = wf;
            app.LoadTorqueProfileDropDown.Value = loadProfile;
        end

        function val = parseValue(~, inputStr)
            % Parse a number typed into a dialog box, accepting '2.5e-3' style
            % input and returning NaN if it is not a valid finite number.
            str = lower(strtrim(inputStr));
            tokens = regexp(str, '^([+-]?\d*\.?\d+(?:e[+-]?\d+)?)([a-z]*)$', 'tokens');
            if isempty(tokens)
                val = str2double(str);
                return;
            end
            numVal = str2double(tokens{1}{1});
            suffix = tokens{1}{2};
            switch suffix
                case 'f',   mult = 1e-15;
                case 'p',   mult = 1e-12;
                case 'n',   mult = 1e-9;
                case 'u',   mult = 1e-6;
                case 'm',   mult = 1e-3;
                case 'k',   mult = 1e3;
                case 'meg', mult = 1e6;
                case 'g',   mult = 1e9;
                case 't',   mult = 1e12;
                otherwise,  mult = 1;
            end
            val = numVal * mult;
        end

        function updatePlots(app, p, simData)
            % Reset hold states
            cla(app.UIAxes);   hold(app.UIAxes,   'off');
            cla(app.UIAxes_2); hold(app.UIAxes_2, 'off');
            cla(app.UIAxes_3); hold(app.UIAxes_3, 'off');
            cla(app.UIAxes_4); hold(app.UIAxes_4, 'off');
            cla(app.UIAxes_5); hold(app.UIAxes_5, 'off');
            cla(app.UIAxes_6); hold(app.UIAxes_6, 'off');
            cla(app.TorqueSpeedAxes); hold(app.TorqueSpeedAxes, 'off');

            t = simData.t; ia = simData.ia; w = simData.w; theta = simData.theta;
            Te = simData.Te; TL = simData.TL; eb = simData.eb; Va = simData.Va;
            method = simData.method;
            isBoth = strcmp(method, 'Both');

            % 1. Armature current
            plot(app.UIAxes, t, ia, 'b-', 'LineWidth', 1.5);
            if strcmp(method, 'Euler')
                iaLegend = {'Euler'};
            elseif strcmp(method, 'RK4') || isBoth
                iaLegend = {'RK4'};
            else
                iaLegend = {'RK45 (Adaptive)'};
            end

            if isBoth
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, t, simData.euler_ia, 'r--', 'LineWidth', 1.5);
                hold(app.UIAxes, 'off');
                iaLegend = {'RK4', 'Euler'};
            end

            if ~isempty(app.ImportedMeasuredTime) && ~isempty(app.ImportedMeasuredCurrent)
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, app.ImportedMeasuredTime, app.ImportedMeasuredCurrent, 'k:', 'LineWidth', 1.5);
                hold(app.UIAxes, 'off');
                iaLegend = [iaLegend, {'Measured'}];
            end

            if isBoth || ~isempty(app.ImportedMeasuredTime)
                legend(app.UIAxes, iaLegend, 'Location', 'best');
            else
                legend(app.UIAxes, 'off');
            end

            % 2. Motor speed
            plot(app.UIAxes_4, t, w, 'b-', 'LineWidth', 1.5);
            wLegend = iaLegend(1);
            if isBoth
                hold(app.UIAxes_4, 'on');
                plot(app.UIAxes_4, t, simData.euler_w, 'r--', 'LineWidth', 1.5);
                hold(app.UIAxes_4, 'off');
                wLegend = {'RK4', 'Euler'};
            end
            if ~isempty(app.ImportedMeasuredTime) && ~isempty(app.ImportedMeasuredSpeed)
                hold(app.UIAxes_4, 'on');
                plot(app.UIAxes_4, app.ImportedMeasuredTime, app.ImportedMeasuredSpeed, 'k:', 'LineWidth', 1.5);
                hold(app.UIAxes_4, 'off');
                wLegend = [wLegend, {'Measured'}];
            end
            if isBoth || ~isempty(app.ImportedMeasuredTime)
                legend(app.UIAxes_4, wLegend, 'Location', 'best');
            else
                legend(app.UIAxes_4, 'off');
            end

            % 3. Other plots
            plot(app.UIAxes_2, t, Va,    'r', 'LineWidth', 1.5);
            plot(app.UIAxes_3, t, eb,    'g', 'LineWidth', 1.5);
            plot(app.UIAxes_5, t, theta, 'm', 'LineWidth', 1.5);
            plot(app.UIAxes_6, t, Te, 'c', t, TL, 'r--', 'LineWidth', 1.5);
            legend(app.UIAxes_6, 'Electromagnetic (Te)', 'Load (TL)', 'Location', 'best');

            % Torque-speed characteristic: the simulated trajectory only.
            % Te = Kt*ia is plotted against omega, so for an armature-controlled
            % DC motor this traces the straight line Te = Kt(Va - Ke*w)/Ra.
            plot(app.TorqueSpeedAxes, w, Te, 'b-', 'LineWidth', 1.5);
            hold(app.TorqueSpeedAxes, 'on');
            if isBoth
                plot(app.TorqueSpeedAxes, simData.euler_w, p.Kt * simData.euler_ia, 'r--', 'LineWidth', 1.5);
            end

            % Operating point = where the simulated run ends. This is the last
            % point of the solved trajectory, not an analytical curve: once the
            % speed stops changing, torque produced = torque absorbed, which is
            % the definition of the operating point.
            opName = {};
            if abs(w(end) - w(max(1, end-round(0.02*numel(w))))) < 0.05   % settled?
                plot(app.TorqueSpeedAxes, w(end), Te(end), 'ko', ...
                     'MarkerSize', 8, 'MarkerFaceColor', [1 0.6 0], 'LineWidth', 1);
                text(app.TorqueSpeedAxes, w(end), Te(end), ...
                     sprintf('  %.1f rad/s, %.3f N\\cdotm', w(end), Te(end)), ...
                     'FontSize', 9, 'VerticalAlignment', 'top');
                opName = {'Operating point'};
            end

            if isBoth
                legend(app.TorqueSpeedAxes, [{'RK4', 'Euler'}, opName], 'Location', 'best');
            elseif ~isempty(opName)
                legend(app.TorqueSpeedAxes, [{'Simulated'}, opName], 'Location', 'best');
            else
                legend(app.TorqueSpeedAxes, 'off');
            end
            hold(app.TorqueSpeedAxes, 'off');
            app.TorqueSpeedAxes.XLimMode = 'auto';
            app.TorqueSpeedAxes.YLimMode = 'auto';

            % 4. Table
            Time_Col = t'; Current_Col = ia'; Speed_Col = w';
            Position_Col = theta'; ElecTorque_Col = Te'; LoadTorque_Col = TL'; BackEMF_Col = eb';
            sim_data = table(Time_Col, Current_Col, Speed_Col, Position_Col, ElecTorque_Col, LoadTorque_Col, BackEMF_Col, ...
                'VariableNames', {'Time (s)', 'Current (A)', 'Speed (rad/s)', 'Position (rad)', 'Te (N.m)', 'TL (N.m)', 'Eb (V)'});
            app.UITable.Data = sim_data;
            app.UITable.ColumnName = sim_data.Properties.VariableNames;

            % 5. Axis limits
            app.UIAxes.XLim   = [0, p.t_end];
            app.UIAxes_2.XLim = [0, p.t_end];
            app.UIAxes_3.XLim = [0, p.t_end];
            app.UIAxes_4.XLim = [0, p.t_end];
            app.UIAxes_5.XLim = [0, p.t_end];
            app.UIAxes_6.XLim = [0, p.t_end];
        end

        function setupAndRunAnimation(app, p, simData)
            cla(app.AnimationAxes);
            enableDefaultInteractivity(app.AnimationAxes);
            app.AnimationAxes.Toolbar.Visible = 'on';
            if ~isempty(app.AnimationAxes.Interactions)
                interactionsList = app.AnimationAxes.Interactions;
                keepIdx = true(size(interactionsList));
                for i = 1:length(interactionsList)
                    if contains(class(interactionsList(i)), 'DataTip') || contains(class(interactionsList(i)), 'Cursor')
                        keepIdx(i) = false;
                    end
                end
                app.AnimationAxes.Interactions = interactionsList(keepIdx);
            end
            view(app.AnimationAxes, [-35 25]);
            axis(app.AnimationAxes, 'equal');
            axis(app.AnimationAxes, 'off');
            app.AnimationAxes.XLim = [-3 2];
            app.AnimationAxes.YLim = [-2 2];
            app.AnimationAxes.ZLim = [-1 1.5];
            app.AnimationAxes.Color = [0.96 0.96 0.96];
            app.Time000sLabel.Text = 'Time: 0.00 s';
            app.TempLabel.Text = 'Temp: 25.0 °C';
            hold(app.AnimationAxes, 'on');
            camlight(app.AnimationAxes, 'left');
            lighting(app.AnimationAxes, 'gouraud');
            th_mag = linspace(60, 120, 20) * pi/180;
            r_in = 0.9; r_out = 1.4; x_mag = [-0.5, 1.5];
            [X_N, Th_N] = meshgrid(x_mag, th_mag);
            Y_in_N = r_in * sin(Th_N); Z_in_N = r_in * cos(Th_N);
            Y_out_N = r_out * sin(Th_N); Z_out_N = r_out * cos(Th_N);
            surf(app.AnimationAxes, X_N, Y_in_N, Z_in_N, 'FaceColor', [0.8 0 0], 'EdgeColor', 'none');
            surf(app.AnimationAxes, X_N, Y_out_N, Z_out_N, 'FaceColor', [0 0 0.8], 'EdgeColor', 'none');
            patch(app.AnimationAxes, [x_mag(1) x_mag(2) x_mag(2) x_mag(1)], [Y_in_N(1,1) Y_in_N(1,2) Y_out_N(1,2) Y_out_N(1,1)], [Z_in_N(1,1) Z_in_N(1,2) Z_out_N(1,2) Z_out_N(1,1)], [0.8 0 0], 'EdgeColor', 'none');
            patch(app.AnimationAxes, [x_mag(1) x_mag(2) x_mag(2) x_mag(1)], [Y_in_N(end,1) Y_in_N(end,2) Y_out_N(end,2) Y_out_N(end,1)], [Z_in_N(end,1) Z_in_N(end,2) Z_out_N(end,2) Z_out_N(end,1)], [0.8 0 0], 'EdgeColor', 'none');
            patch(app.AnimationAxes, [X_N(:,1)' flip(X_N(:,1)')], [Y_in_N(:,1)' flip(Y_out_N(:,1)')], [Z_in_N(:,1)' flip(Z_out_N(:,1)')], [0.8 0 0], 'EdgeColor', 'none');
            patch(app.AnimationAxes, [X_N(:,2)' flip(X_N(:,2)')], [Y_in_N(:,2)' flip(Y_out_N(:,2)')], [Z_in_N(:,2)' flip(Z_out_N(:,2)')], [0.8 0 0], 'EdgeColor', 'none');
            th_S = linspace(240, 300, 20) * pi/180;
            [X_S, Th_S] = meshgrid(x_mag, th_S);
            Y_in_S = r_in * sin(Th_S); Z_in_S = r_in * cos(Th_S);
            Y_out_S = r_out * sin(Th_S); Z_out_S = r_out * cos(Th_S);
            surf(app.AnimationAxes, X_S, Y_in_S, Z_in_S, 'FaceColor', [0 0 0.8], 'EdgeColor', 'none');
            surf(app.AnimationAxes, X_S, Y_out_S, Z_out_S, 'FaceColor', [0.8 0 0], 'EdgeColor', 'none');
            patch(app.AnimationAxes, [x_mag(1) x_mag(2) x_mag(2) x_mag(1)], [Y_in_S(1,1) Y_in_S(1,2) Y_out_S(1,2) Y_out_S(1,1)], [Z_in_S(1,1) Z_in_S(1,2) Z_out_S(1,2) Z_out_S(1,1)], [0 0 0.8], 'EdgeColor', 'none');
            patch(app.AnimationAxes, [x_mag(1) x_mag(2) x_mag(2) x_mag(1)], [Y_in_S(end,1) Y_in_S(end,2) Y_out_S(end,2) Y_out_S(end,1)], [Z_in_S(end,1) Z_in_S(end,2) Z_out_S(end,2) Z_out_S(end,1)], [0 0 0.8], 'EdgeColor', 'none');
            patch(app.AnimationAxes, [X_S(:,1)' flip(X_S(:,1)')], [Y_in_S(:,1)' flip(Y_out_S(:,1)')], [Z_in_S(:,1)' flip(Z_out_S(:,1)')], [0 0 0.8], 'EdgeColor', 'none');
            patch(app.AnimationAxes, [X_S(:,2)' flip(X_S(:,2)')], [Y_in_S(:,2)' flip(Y_out_S(:,2)')], [Z_in_S(:,2)' flip(Z_out_S(:,2)')], [0 0 0.8], 'EdgeColor', 'none');
            F_box = [1 2 3 4; 5 6 7 8; 1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8];
            brush_color = [0.85 0.85 0.85];
            V_b1_vert = [-1.3 0.22 -0.4; -1.1 0.22 -0.4; -1.1 0.35 -0.4; -1.3 0.35 -0.4; -1.3 0.22  0.2; -1.1 0.22  0.2; -1.1 0.35  0.2; -1.3 0.35  0.2];
            V_b1_horz = [-1.3 0.22 -0.55; -1.1 0.22 -0.55; -1.1 0.8 -0.55; -1.3 0.8 -0.55; -1.3 0.22 -0.4; -1.1 0.22 -0.4; -1.1 0.8 -0.4; -1.3 0.8 -0.4];
            patch(app.AnimationAxes, 'Vertices', V_b1_vert, 'Faces', F_box, 'FaceColor', brush_color, 'EdgeColor', 'none');
            patch(app.AnimationAxes, 'Vertices', V_b1_horz, 'Faces', F_box, 'FaceColor', brush_color, 'EdgeColor', 'none');
            V_b2_vert = V_b1_vert; V_b2_vert(:,2) = -V_b2_vert(:,2);
            V_b2_horz = V_b1_horz; V_b2_horz(:,2) = -V_b2_horz(:,2);
            patch(app.AnimationAxes, 'Vertices', V_b2_vert, 'Faces', F_box, 'FaceColor', brush_color, 'EdgeColor', 'none');
            patch(app.AnimationAxes, 'Vertices', V_b2_horz, 'Faces', F_box, 'FaceColor', brush_color, 'EdgeColor', 'none');
            plot3(app.AnimationAxes, [-1.2, -2.5], [0.7, 1.8], [-0.47, -0.47], 'k-', 'LineWidth', 5);
            plot3(app.AnimationAxes, [-1.2, -2.5], [-0.7, -1.8], [-0.47, -0.47], 'k-', 'LineWidth', 5);
            text(app.AnimationAxes, -2.7, 1.9, -0.47, '+', 'Color', 'r', 'FontSize', 30, 'FontWeight', 'bold');
            text(app.AnimationAxes, -2.7, -1.9, -0.47, '-', 'Color', 'k', 'FontSize', 40, 'FontWeight', 'bold');
            tform = hgtransform('Parent', app.AnimationAxes);
            if ~isvalid(app.AnimationAxes)
                return;
            end
            r_comm = 0.2;
            th_c1 = linspace(-pi/2+0.1, pi/2-0.1, 20);
            [Xc1, Thc1] = meshgrid([-1.4, -1.0], th_c1);
            surf(app.AnimationAxes, Xc1, r_comm*sin(Thc1), r_comm*cos(Thc1), 'FaceColor', [0.6 0.6 0.6], 'EdgeColor', 'none', 'Parent', tform);
            th_c2 = linspace(pi/2+0.1, 3*pi/2-0.1, 20);
            [Xc2, Thc2] = meshgrid([-1.4, -1.0], th_c2);
            surf(app.AnimationAxes, Xc2, r_comm*sin(Thc2), r_comm*cos(Thc2), 'FaceColor', [0.6 0.6 0.6], 'EdgeColor', 'none', 'Parent', tform);
            cw_x = [-1.2, -0.8, -0.8,  1.0, 1.0, -0.8, -0.8, -1.2];
            cw_y = [ 0.2,  0.2,  0.7,  0.7, -0.7, -0.7, -0.2, -0.2];
            cw_z = [ 0,    0,    0,    0,    0,    0,    0,    0];
            plot3(app.AnimationAxes, cw_x, cw_y, cw_z, 'Color', [0.5 0.5 0.5], 'LineWidth', 5, 'Parent', tform);
            hold(app.AnimationAxes, 'off');
            app.TabGroup.SelectedTab = app.AnimationTab;
            drawnow;
            set(app.AnimationAxes.Children, 'HitTest', 'off');
            % Filter out duplicate time points from adaptive solvers
            [t_unq, idx] = unique(simData.t);
            theta_unq = simData.theta(idx);
            temp_unq = simData.MotorTemp(idx);
            playback_speed = 5.0; % Run 5x faster than real-time
            tStart = tic;
            t_real = 0;
            app.IsRunning = true;
            while t_real < p.t_end && app.IsRunning
                if ~isvalid(app.AnimationAxes) || ~isvalid(tform)
                    break;
                end
                t_real = toc(tStart) * playback_speed;
                if t_real > p.t_end
                    t_real = p.t_end;
                end
                current_theta = interp1(t_unq, theta_unq, t_real, 'linear', 'extrap');
                current_temp  = interp1(t_unq, temp_unq, t_real, 'linear', 'extrap');

                tform.Matrix = makehgtform('xrotate', current_theta);
                app.Time000sLabel.Text = sprintf('Time: %.2f s', t_real);
                app.TempLabel.Text = sprintf('Temp: %.1f °C', current_temp);
                drawnow;
                pause(0.015);
            end
            if isvalid(app)
                app.IsRunning = false;
            end
            sim_data_ws = table(simData.t', simData.ia', simData.w', simData.theta', simData.MotorTemp', 'VariableNames', {'Time', 'Current', 'Speed', 'Position', 'Temperature'});
            assignin('base', 'MotorSimData', sim_data_ws);
        end

    end

    % =====================================================================
    %  CALLBACKS - one per button, drop-down or other user action
    % =====================================================================

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Runs once when the app opens, and again whenever Reset All is
            % pressed, so this is the single place that defines "default state".
            app.bindReadableNames();

            % State the machine type on screen. The model assumes a CONSTANT
            % field, so Kt and Ke are constants - that is a permanent-magnet
            % (or separately excited) DC motor, not a shunt, series or
            % compound machine, whose flux would vary with current.
            app.MotorParameterInputsPanel.Title = ...
                'Motor-Parameter Inputs  -  Permanent-Magnet (Armature-Controlled) DC Motor';

            % Single source of default values, used at launch AND by Reset:
            % test case M1 (dc_motor_test_cases.csv) + the option defaults below.
            % The design-time values in Design View are set to the same numbers.
            app.buildPlotLayout();
            app.captureOrRestoreDefaultView('capture');

            app.CoulombFrictionEditField.Value = 0;

            % Initial Position (rad) - required by the handbook (algorithm step 5).
            % Created here once; Reset calls startupFcn again, so guard it.
            if isempty(app.InitialPositionEditField) || ~isvalid(app.InitialPositionEditField)
                app.InitialPositionEditFieldLabel = uilabel(app.MotorParameterInputsPanel);
                app.InitialPositionEditFieldLabel.HorizontalAlignment = 'right';
                app.InitialPositionEditFieldLabel.FontName = 'Times New Roman';
                app.InitialPositionEditFieldLabel.FontSize = 16;
                app.InitialPositionEditFieldLabel.Position = [12 14 190 22];
                app.InitialPositionEditFieldLabel.Text = 'Initial Position';

                app.InitialPositionEditField = uieditfield(app.MotorParameterInputsPanel, 'numeric');
                app.InitialPositionEditField.HorizontalAlignment = 'center';
                app.InitialPositionEditField.FontName = 'Times New Roman';
                app.InitialPositionEditField.FontSize = 14;
                app.InitialPositionEditField.Position = [215 14 97 22];
            end
            app.InitialPositionEditField.Value = 0;

            % Required/extended voltage choices.
            app.VoltageWaveformDropDown.Items = { ...
                'Step', 'Ramp', 'Pulse', 'Imported File', 'User-defined'};

            app.VoltageWaveformDropDown.Value = 'Step';

            % Required/extended load choices.
            % Load characteristics (torque vs speed), switched on at the
            % Load Application Time.
            app.LoadTorqueProfileDropDown.Items = { ...
                'Constant (T = K)', 'Linear (T ∝ ω)', 'Quadratic (T ∝ ω²)', ...
                'Constant Power (T ∝ 1/ω)', 'Imported File'};

            app.LoadTorqueProfileDropDown.Value = 'Constant (T = K)';

            % Ramp / pulse timing (test cases M2, M3).
            app.RampDuration   = 0.5;
            app.PulseStartTime = 0.2;
            app.PulseEndTime   = 1.5;

            app.NumericalMethodDropDown.Items = { ...
                'Euler', 'RK4', 'RK45 (Adaptive)', 'Both'};
            app.NumericalMethodDropDown.Value = 'Both';

            app.EnableCurrentLimitCheckBox.Value = false;
            app.MaxCurrentAEditField.Value = 15;

            % These are actual UI defaults, not overwritten by getSimParams().
            % Current limit and thermal model are OFF by default so the app
            % opens on the plain textbook model: the torque-speed transient
            % then lies on the theoretical steady-state line.
            app.EnableThermalModelCheckBox.Value = false;
            app.EnableCoulombFrictionCheckBox.Value = false;

            app.RunStatusLabel.Text = 'Status: Ready';
            app.TimeStepStatusLabel.Text = 'Status: Ready';
            app.CompareStatusLabel.Text = 'Status: Ready';

            % Motor A, initial conditions, 24 V step, 0.05 N.m at 1 s, 3 s, h = 1e-4.
            app.applyTestCase('M1');
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            if app.IsRunning
                return;
            end
            app.IsRunning = true;
            cleanupObj = onCleanup(@() app.setRunningFalse());
            app.RunStatusLabel.Text = 'Status: Simulating...';
            app.RunStatusLabel.FontColor = 'red';
            drawnow;
            try
                if app.SimulationDurationEditField.Value <= 0 || app.TimeStepEditField.Value <= 0
                    uialert(app.UIFigure, 'Simulation Duration and Time Step must be greater than zero.', 'Invalid Input');
                    app.RunStatusLabel.Text = 'Status: Ready';
                    app.RunStatusLabel.FontColor = 'green';
                    return;
                end
                p = app.getSimParams();
                if p.Ra <= 0 || p.La <= 0 || p.Kt < 0 || p.Ke < 0 || p.J <= 0 || p.B < 0 || p.ILimit <= 0
                    uialert(app.UIFigure, 'Check motor parameters. R, L, J and current limit must be positive; friction/constants cannot be negative.', 'Invalid Motor Parameters');
                    app.RunStatusLabel.Text = 'Status: Ready';
                    app.RunStatusLabel.FontColor = 'green';
                    return;
                end
                [Va_func, success] = app.getVoltageFunction(p.V_mag, p.t_end);
                if ~success
                    app.RunStatusLabel.Text = 'Status: Ready';
                    app.RunStatusLabel.FontColor = 'green';
                    return;
                end
                TL_func = app.getTorqueFunction(p);
                method  = app.NumericalMethodDropDown.Value;
                simData = DCMotorSimulator.simulateDCMotor(p, Va_func, TL_func, method);
                if simData.unstable
                    uialert(app.UIFigure, 'Numerical instability detected. Reduce the time step or check the model parameters.', 'Simulation Failed', 'Icon', 'error');
                    app.RunStatusLabel.Text = 'Status: Ready';
                    app.RunStatusLabel.FontColor = 'green';
                    return;
                end
                app.updatePlots(p, simData);
                app.RunStatusLabel.Text = 'Status: Playing...';
                app.RunStatusLabel.FontColor = 'green';
                drawnow;
                app.setupAndRunAnimation(p, simData);
                app.RunStatusLabel.Text = 'Status: Ready';
                app.RunStatusLabel.FontColor = 'green';
            catch ME
                app.RunStatusLabel.Text = 'Status: Ready';
                app.RunStatusLabel.FontColor = 'green';
                uialert(app.UIFigure, ['Simulation error: ', ME.message], 'Simulation Error', 'Icon', 'error');
            end
        end

        % Button pushed function: ExportResultsButton_2
        function ExportResultsButton_2Pushed(app, event)
            if isempty(app.ResultsTable.Data)
                uialert(app.UIFigure, 'No simulation data available to export. Please run a simulation first.', 'Export Error');
                return;
            end
            [file, path] = uiputfile({'*.csv', 'CSV File (*.csv)'; '*.xlsx', 'Excel Workbook (*.xlsx)'}, 'Save Simulation Results');

            if isequal(file, 0)
                return;
            end

            fullFileName = fullfile(path, file);

            try
                writetable(app.ResultsTable.Data, fullFileName);
                uialert(app.UIFigure, ['Data successfully exported to ', file], 'Export Successful', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, ['Error saving file: ', ME.message], 'Export Failed', 'Icon', 'error');
            end
        end

        % Button pushed function: CompareMethodsButton
        function CompareMethodsButtonPushed(app, event)
            if app.IsRunning
                return;
            end
            app.IsRunning = true;
            cleanupObj = onCleanup(@() app.setRunningFalse());
            app.CompareStatusLabel.Text = 'Status: Simulating...';
            app.CompareStatusLabel.FontColor = 'red';
            drawnow;
 
            try
                if app.SimulationDurationEditField.Value <= 0 || app.TimeStepEditField.Value <= 0
                    uialert(app.UIFigure, 'Simulation Duration and Time Step must be greater than zero.', 'Invalid Input');
                    app.CompareStatusLabel.Text = 'Status: Ready';
                    app.CompareStatusLabel.FontColor = 'green';
                    return;
                end
 
                p = app.getSimParams();
                if p.Ra <= 0 || p.La <= 0 || p.Kt < 0 || p.Ke < 0 || p.J <= 0 || p.B < 0 || p.ILimit <= 0
                    uialert(app.UIFigure, 'Check motor parameters. R, L, J and current limit must be positive; friction/constants cannot be negative.', 'Invalid Motor Parameters');
                    app.CompareStatusLabel.Text = 'Status: Ready';
                    app.CompareStatusLabel.FontColor = 'green';
                    return;
                end
 
                [Va_func, success] = app.getVoltageFunction(p.V_mag, p.t_end);
                if ~success
                    app.CompareStatusLabel.Text = 'Status: Ready';
                    app.CompareStatusLabel.FontColor = 'green';
                    return;
                end
                TL_func = app.getTorqueFunction(p);
 
                % Same time grid and same model as the main solver.
                t = DCMotorSimulator.makeTimeVector(p.t_end, p.h);
                x_euler = DCMotorSimulator.runEulerFixedStep(p, Va_func, TL_func, t);
                x_rk4   = DCMotorSimulator.runRK4FixedStep(p, Va_func, TL_func, t);
 
                w_euler = x_euler(2,:);
                w_rk4   = x_rk4(2,:);
 
                unstable = any(~isfinite(x_euler(:))) || any(~isfinite(x_rk4(:))) || max(abs(w_euler), [], 'omitnan') > 1e6 || max(abs(w_rk4),   [], 'omitnan') > 1e6;
 
                if unstable
                    uialert(app.UIFigure, 'Numerical instability detected. Reduce the time step.', 'Simulation Failed', 'Icon', 'error');
                    app.CompareStatusLabel.Text = 'Status: Ready';
                    app.CompareStatusLabel.FontColor = 'green';
                    return;
                end
 
                cla(app.MethodCompareAxes);
                plot(app.MethodCompareAxes, t, w_euler, 'r--', 'LineWidth', 1.5, 'DisplayName', 'Euler Method');
                hold(app.MethodCompareAxes, 'on');
                plot(app.MethodCompareAxes, t, w_rk4, 'b-', 'LineWidth', 1.5, 'DisplayName', 'RK4 Method');
                hold(app.MethodCompareAxes, 'off');
 
                grid(app.MethodCompareAxes, 'on');
                legend(app.MethodCompareAxes, 'Location', 'best');
                title(app.MethodCompareAxes, 'Euler vs RK4 Speed Response');
                xlabel(app.MethodCompareAxes, 'Time (s)');
                ylabel(app.MethodCompareAxes, 'Motor Speed (rad/s)');
                app.MethodCompareAxes.XLim = [0, p.t_end];
                app.TabGroup.SelectedTab = app.PlotsTab;
                app.showPlotView('Numerical Analysis');
 
                app.CompareStatusLabel.Text = 'Status: Ready';
                app.CompareStatusLabel.FontColor = 'green';
 
            catch ME
                app.CompareStatusLabel.Text = 'Status: Ready';
                app.CompareStatusLabel.FontColor = 'green';
                uialert(app.UIFigure, ['Compare error: ', ME.message], 'Compare Error', 'Icon', 'error');
            end
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            app.IsRunning = false;    % also stops a running animation
 
            % Clear every plot (including the animation) and restore titles,
            % labels, scales, legends, selected tab, table and status colours
            % to exactly how the app looks when it is first opened.
            app.captureOrRestoreDefaultView('restore');
 
            % Animation labels
            app.SimTimeLabel.Text = 'Time: 0.00 s';
            app.MotorTempLabel.Text = 'Temp: 25.0 °C';
 
            % Clear imported data
            app.ImportedMeasuredTime    = [];
            app.ImportedMeasuredCurrent = [];
            app.ImportedMeasuredSpeed   = [];
            app.ImportedTime    = [];
            app.ImportedVoltage = [];
            app.ImportedLoad    = [];
 
            % Restore exactly the launch defaults (test case M1).
            app.startupFcn();
        end

        % Button pushed function: TimeStepStudyButton
        function TimeStepStudyButtonPushed(app, event)
            if app.IsRunning
                return;
            end
            app.IsRunning = true;
            cleanupObj = onCleanup(@() app.setRunningFalse());
            app.TimeStepStatusLabel.Text = 'Status: Simulating...';
            app.TimeStepStatusLabel.FontColor = 'red';
            drawnow;
            try
                if app.SimulationDurationEditField.Value <= 0 || app.TimeStepEditField.Value <= 0
                    uialert(app.UIFigure, 'Simulation Duration and Time Step must be greater than zero.', 'Invalid Input');
                    app.TimeStepStatusLabel.Text = 'Status: Ready';
                    app.TimeStepStatusLabel.FontColor = 'green';
                    return;
                end
                p = app.getSimParams();
                [Va_func, success] = app.getVoltageFunction(p.V_mag, p.t_end);
                if ~success
                    app.TimeStepStatusLabel.Text = 'Status: Ready';
                    app.TimeStepStatusLabel.FontColor = 'green';
                    return;
                end
                TL_func = app.getTorqueFunction(p);
                % ode45 is used ONLY as a validation/reference solution.
                % Tight tolerances so the reference error stays well below RK4's
                % error, and deval (ode45's own continuous interpolant) instead of
                % linear interpolation between ode45 output points.
                ode_fun = @(t,x) DCMotorSimulator.motorDerivative(p, Va_func(t), TL_func, t, x);
                options = odeset('RelTol', 1e-10, 'AbsTol', 1e-10);
                ref_sol = ode45(ode_fun, [0 p.t_end], [p.ia0; p.w0; p.th0; p.Tamb], options);
                % A run is unstable / physically unreasonable if it is non-finite
                % or the speed exceeds a sanity bound.
                isUnstable = @(x) any(~isfinite(x(:))) || max(abs(x(2,:))) > 1e6;
                % ---- Numerical error vs time (RK4 at the user's h) ----
                t_curr = DCMotorSimulator.makeTimeVector(p.t_end, p.h);
                x_curr = DCMotorSimulator.runRK4FixedStep(p, Va_func, TL_func, t_curr);
                x_ref_curr    = deval(ref_sol, t_curr);
                error_vs_time = abs(x_curr(2,:) - x_ref_curr(2,:));
                cla(app.ErrorVsTimeAxes);
                if isUnstable(x_curr)
                    error_vs_time(:) = NaN;
                    grid(app.ErrorVsTimeAxes, 'on');
                    title(app.ErrorVsTimeAxes, {'Numerical Error vs Time (RK4)', sprintf('RK4 is unstable at h = %g s - reduce the time step', p.h)});
                else
                    plot(app.ErrorVsTimeAxes, t_curr, error_vs_time, 'LineWidth', 1.5);
                    grid(app.ErrorVsTimeAxes, 'on');
                    title(app.ErrorVsTimeAxes, 'Numerical Error vs Time (RK4)');
                end
                xlabel(app.ErrorVsTimeAxes, 'Time (s)');
                ylabel(app.ErrorVsTimeAxes, 'Absolute Speed Error (rad/s)');
                app.ErrorVsTimeAxes.XLim = [0 p.t_end];
                max_err = max(error_vs_time, [], 'omitnan');
                if isempty(max_err) || ~isfinite(max_err) || max_err <= 0
                    app.ErrorVsTimeAxes.YLim = [0 1e-6];
                else
                    app.ErrorVsTimeAxes.YLim = [0 1.1*max_err];
                end
                % ---- Max error vs step size ----
                h_array = unique(sort([0.1 0.05 0.01 0.005 0.001 p.h]));
                h_array = h_array(h_array > 0 & h_array < p.t_end);
                if isempty(h_array)
                    h_array = p.h;
                end
                max_error_euler = nan(size(h_array));
                max_error_rk4   = nan(size(h_array));
                for k = 1:numel(h_array)
                    h_test = h_array(k);
                    t_test = DCMotorSimulator.makeTimeVector(p.t_end, h_test);
 
                    x_e = DCMotorSimulator.runEulerFixedStep(p, Va_func, TL_func, t_test);
                    x_r = DCMotorSimulator.runRK4FixedStep(p, Va_func, TL_func, t_test);
 
                    x_ref_test = deval(ref_sol, t_test);
                    w_ref_test = x_ref_test(2,:);
 
                    % Diverged runs are left as NaN (gap in the plot) instead of
                    % astronomically large values that swamp the log axis.
                    if ~isUnstable(x_e)
                        max_error_euler(k) = max(abs(x_e(2,:) - w_ref_test));
                    end
                    if ~isUnstable(x_r)
                        max_error_rk4(k) = max(abs(x_r(2,:) - w_ref_test));
                    end
                end
                unstableE = h_array(isnan(max_error_euler));
                unstableR = h_array(isnan(max_error_rk4));
                cla(app.ErrorVsStepSizeAxes);
                loglog(app.ErrorVsStepSizeAxes, h_array, max_error_euler, '-o', 'LineWidth', 1.5, 'DisplayName', 'Euler Error');
                hold(app.ErrorVsStepSizeAxes, 'on');
                loglog(app.ErrorVsStepSizeAxes, h_array, max_error_rk4, '-s', 'LineWidth', 1.5, 'DisplayName', 'RK4 Error');
                hold(app.ErrorVsStepSizeAxes, 'off');
                grid(app.ErrorVsStepSizeAxes, 'on');
                legend(app.ErrorVsStepSizeAxes, 'Location', 'best');
                ttl = {'Maximum Speed Error vs Time-Step Size'};
                if ~isempty(unstableE) || ~isempty(unstableR)
                    hList = {'none', 'none'};
                    if ~isempty(unstableE), hList{1} = strjoin(arrayfun(@(v) sprintf('%g', v), unstableE, 'UniformOutput', false), ', '); end
                    if ~isempty(unstableR), hList{2} = strjoin(arrayfun(@(v) sprintf('%g', v), unstableR, 'UniformOutput', false), ', '); end
                    ttl{end+1} = sprintf('Unstable (omitted): Euler h = %s | RK4 h = %s', hList{1}, hList{2});
                end
                title(app.ErrorVsStepSizeAxes, ttl);
                xlabel(app.ErrorVsStepSizeAxes, 'Time Step Size (s)');
                ylabel(app.ErrorVsStepSizeAxes, 'Maximum Error (rad/s)');
                app.TabGroup.SelectedTab = app.PlotsTab;
                app.showPlotView('Numerical Analysis');
                app.TimeStepStatusLabel.Text = 'Status: Ready';
                app.TimeStepStatusLabel.FontColor = 'green';
            catch ME
                app.TimeStepStatusLabel.Text = 'Status: Ready';
                app.TimeStepStatusLabel.FontColor = 'green';
                uialert(app.UIFigure, ['Time-step study error: ', ME.message], 'Study Error', 'Icon', 'error');
            end
        end

        % Button down function: UIAxes_8
        function UIAxes_8ButtonDown(app, event)
            app.ErrorVsTimeAxes.XLim = [0, app.SimulationDurationEditField.Value];
            axis(app.ErrorVsTimeAxes, 'auto');
        end

        % Button pushed function: ImportWaveformButton
        function ImportWaveformButtonPushed(app, event)
            [file, path] = uigetfile('*.csv', 'Select Custom Profile CSV');

            if isequal(file, 0)
                return;
            end

            try
                dataTable = readtable(fullfile(path, file));

                requiredNames = {'Time_s', 'Applied_Voltage_V', 'Load_Torque_Nm'};

                if ~all(ismember(requiredNames, dataTable.Properties.VariableNames))
                    error(['CSV must contain columns: Time_s, ', 'Applied_Voltage_V, Load_Torque_Nm']);
                end

                t_imp = dataTable.Time_s;
                v_imp = dataTable.Applied_Voltage_V;
                tl_imp = dataTable.Load_Torque_Nm;

                [t_imp, order] = sort(t_imp(:));
                v_imp = v_imp(order);
                tl_imp = tl_imp(order);

                if numel(t_imp) < 2 || any(~isfinite(t_imp)) || any(~isfinite(v_imp)) || any(~isfinite(tl_imp))
                    error('Imported data must contain at least two finite rows.');
                end

                if any(diff(t_imp) <= 0)
                    error('Time_s must contain strictly increasing, unique values.');
                end

                app.ImportedTime = t_imp;
                app.ImportedVoltage = v_imp;
                app.ImportedLoad = tl_imp;

                % Select the imported profile and apply test case M4
                % (Motor A, duration = profile length, h = 1 ms).
                app.applyTestCase('M4');

                uialert(app.UIFigure, sprintf('Voltage and load profile loaded (%.3g s). Test case M4 settings applied: Motor A, h = %g s.', ...
                    app.SimulationDurationEditField.Value, app.TimeStepEditField.Value), 'Import Success', 'Icon', 'success');

            catch ME
                uialert(app.UIFigure, ['Failed to read waveform file: ', ME.message], 'Import Error', 'Icon', 'error');
            end
        end

        % Button pushed function: ImportMeasuredDataButton
        function ImportMeasuredDataButtonPushed(app, event)
            [file, path] = uigetfile({'*.csv;*.txt', 'Data Files (*.csv, *.txt)'}, 'Select Measured Motor Data (Time, Current, Speed)');

            if isequal(file, 0)
                return;
            end

            try
                data = readmatrix(fullfile(path, file));

                if size(data,2) < 3
                    error('The file must contain at least three numeric columns.');
                end

                data = data(:,1:3);

                finiteRows = all(isfinite(data), 2);
                data = data(finiteRows,:);

                if size(data,1) < 2
                    error('Not enough valid measured-data rows.');
                end

                [t_meas, order] = sort(data(:,1));

                if any(diff(t_meas) <= 0)
                    error('Measured time values must be strictly increasing.');
                end

                i_meas = data(order,2);
                w_meas = data(order,3);

                % Sanity check: catch the common mistake of importing the
                % voltage/load-torque waveform file (meant for Import Waveform)
                % into this button by mistake. A genuine (current, speed)
                % recording from THIS motor should have a current comparable to
                % the current limit and a speed comparable to V/Ke, and should
                % not sit implausibly flat for the whole record.
                i_limit  = app.MaxCurrentAEditField.Value;
                v_mag    = app.SupplyVoltageMagnitudeEditField.Value;
                ke       = app.BackEMFConstantEditField.Value;
                w_noload = max(abs(v_mag) / max(abs(ke), eps), 1);

                warnings = {};
                if max(abs(i_meas)) > 3 * max(i_limit, eps)
                    warnings{end+1} = sprintf(['column 2 ("current") reaches %.3g, which is more than 3x ' ...
                        'the current limit of %.3g A - check this is current in amps, not applied voltage.'], ...
                        max(abs(i_meas)), i_limit);
                end
                if max(abs(w_meas)) < 0.02 * w_noload
                    warnings{end+1} = sprintf(['column 3 ("speed") never exceeds %.3g rad/s, which is under 2%% of ' ...
                        'the no-load speed V/Ke = %.3g rad/s - check this is speed in rad/s, not load torque.'], ...
                        max(abs(w_meas)), w_noload);
                end

                if ~isempty(warnings)
                    msg = ['This file does not look like a (time, current, speed) recording from this motor:' newline newline ...
                           strjoin(warnings, [newline newline]) newline newline ...
                           'If this is really the voltage/load-torque profile, use Import Waveform instead. ' ...
                           'Import anyway?'];
                    choice = uiconfirm(app.UIFigure, msg, 'Check Measured Data', ...
                        'Options', {'Import Anyway', 'Cancel'}, 'DefaultOption', 2, 'Icon', 'warning');
                    if ~strcmp(choice, 'Import Anyway')
                        return;
                    end
                end

                app.ImportedMeasuredTime = t_meas;
                app.ImportedMeasuredCurrent = i_meas;
                app.ImportedMeasuredSpeed = w_meas;

                uialert(app.UIFigure, ['Successfully imported measured data from ', file], 'Import Success', 'Icon', 'success');

            catch ME
                uialert(app.UIFigure, ['Failed to read measured data: ', ME.message], 'Import Error', 'Icon', 'error');
            end
        end

        % Value changed function: MotorProfileDropDown
        function MotorProfileDropDownValueChanged(app, event)
            profile = app.MotorProfileDropDown.Value;

            if strcmp(profile, 'Motor A')
                app.ArmatureResistanceEditField.Value = 1.2;
                app.ArmatureInductanceEditField.Value = 0.015;
                app.TorqueconstantEditField.Value = 0.08;
                app.BackEMFConstantEditField.Value = 0.08;
                app.RotorInertiaEditField.Value = 0.002;
                app.ViscousFrictionCoefficientEditField.Value = 0.002;
            elseif strcmp(profile, 'Motor B')
                app.ArmatureResistanceEditField.Value = 2.0;
                app.ArmatureInductanceEditField.Value = 0.025;
                app.TorqueconstantEditField.Value = 0.1;
                app.BackEMFConstantEditField.Value = 0.1;
                app.RotorInertiaEditField.Value = 0.004;
                app.ViscousFrictionCoefficientEditField.Value = 0.003;
            end
        end

        % Value changed function: VoltageWaveformDropDown
        function VoltageWaveformDropDownValueChanged(app, event)
           waveform = app.VoltageWaveformDropDown.Value;
 
            switch waveform
                case 'Step'
                    app.applyTestCase('M1');
 
                case 'Ramp'
                    app.applyTestCase('M2');
 
                case 'Pulse'
                    app.applyTestCase('M3');
 
                case 'User-defined'
                    app.setMotorParams(1.2, 0.015, 0.08, 0.08, 0.002, 0.002);
                    app.MotorProfileDropDown.Value = 'Motor A';
                    app.SimulationDurationEditField.Value = 3;
                    app.TimeStepEditField.Value           = 0.001;
 
                case 'Imported File'
                    % Test case M4: imported voltage and load profile.
                    app.applyTestCase('M4');
 
            end
        end
    end

    % =====================================================================
    %  COMPONENT INITIALISATION - generated by App Designer.
    %  Edit through Design View, not by hand.
    % =====================================================================
    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MotorParameterInputsPanel = uipanel(app.UIFigure);
            app.MotorParameterInputsPanel.Title = 'Motor-Parameter Inputs';
            app.ArmatureResistanceEditFieldLabel = uilabel(app.MotorParameterInputsPanel);
            app.ArmatureResistanceEditFieldLabel.Text = 'Armature Resistance';
            app.ArmatureResistanceEditField = uieditfield(app.MotorParameterInputsPanel, 'numeric');
            app.ArmatureResistanceEditField.Value = 1.2;
            app.InitialSpeedEditFieldLabel = uilabel(app.MotorParameterInputsPanel);
            app.InitialSpeedEditFieldLabel.Text = 'Initial Speed';
            app.InitialSpeedEditField = uieditfield(app.MotorParameterInputsPanel, 'numeric');
            app.ArmatureInductanceEditFieldLabel = uilabel(app.MotorParameterInputsPanel);
            app.ArmatureInductanceEditFieldLabel.Text = 'Armature Inductance';
            app.ArmatureInductanceEditField = uieditfield(app.MotorParameterInputsPanel, 'numeric');
            app.ArmatureInductanceEditField.Value = 0.015;
            app.TorqueconstantEditFieldLabel = uilabel(app.MotorParameterInputsPanel);
            app.TorqueconstantEditFieldLabel.Text = 'Torque constant';
            app.TorqueconstantEditField = uieditfield(app.MotorParameterInputsPanel, 'numeric');
            app.TorqueconstantEditField.Value = 0.08;
            app.BackEMFConstantEditFieldLabel = uilabel(app.MotorParameterInputsPanel);
            app.BackEMFConstantEditFieldLabel.Text = 'Back-EMF Constant';
            app.BackEMFConstantEditField = uieditfield(app.MotorParameterInputsPanel, 'numeric');
            app.BackEMFConstantEditField.Value = 0.08;
            app.RotorInertiaEditFieldLabel = uilabel(app.MotorParameterInputsPanel);
            app.RotorInertiaEditFieldLabel.Text = 'Rotor Inertia';
            app.RotorInertiaEditField = uieditfield(app.MotorParameterInputsPanel, 'numeric');
            app.RotorInertiaEditField.Value = 0.002;
            app.ViscousFrictionCoefficientEditFieldLabel = uilabel(app.MotorParameterInputsPanel);
            app.ViscousFrictionCoefficientEditFieldLabel.Text = 'Viscous-Friction Coefficient';
            app.ViscousFrictionCoefficientEditField = uieditfield(app.MotorParameterInputsPanel, 'numeric');
            app.ViscousFrictionCoefficientEditField.Value = 0.002;
            app.InitialCurrentEditFieldLabel = uilabel(app.MotorParameterInputsPanel);
            app.InitialCurrentEditFieldLabel.Text = 'Initial Current';
            app.InitialCurrentEditField = uieditfield(app.MotorParameterInputsPanel, 'numeric');
            app.CoulombFrictionEditFieldLabel = uilabel(app.MotorParameterInputsPanel);
            app.CoulombFrictionEditFieldLabel.Text = 'Coulomb Friction';
            app.CoulombFrictionEditField = uieditfield(app.MotorParameterInputsPanel, 'numeric');
            app.MotorProfileDropDownLabel = uilabel(app.MotorParameterInputsPanel);
            app.MotorProfileDropDownLabel.Text = 'Motor Profile';
            app.MotorProfileDropDown = uidropdown(app.MotorParameterInputsPanel);
            app.MotorProfileDropDown.Items = {'Motor A', 'Motor B'};
            app.MotorProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorProfileDropDownValueChanged, true);
            app.MotorProfileDropDown.Value = 'Motor A';
            app.SimulationInputsPanel = uipanel(app.UIFigure);
            app.SimulationInputsPanel.Title = 'Simulation Inputs';
            app.SupplyVoltageMagnitudeEditFieldLabel = uilabel(app.SimulationInputsPanel);
            app.SupplyVoltageMagnitudeEditFieldLabel.Text = 'Supply-Voltage Magnitude';
            app.SupplyVoltageMagnitudeEditField = uieditfield(app.SimulationInputsPanel, 'numeric');
            app.SupplyVoltageMagnitudeEditField.Value = 24;
            app.LoadTorqueMagnitudeEditFieldLabel = uilabel(app.SimulationInputsPanel);
            app.LoadTorqueMagnitudeEditFieldLabel.Text = 'Load-Torque Magnitude';
            app.LoadTorqueMagnitudeEditField = uieditfield(app.SimulationInputsPanel, 'numeric');
            app.LoadTorqueMagnitudeEditField.Value = 0.05;
            app.LoadApplicationTimeEditFieldLabel = uilabel(app.SimulationInputsPanel);
            app.LoadApplicationTimeEditFieldLabel.Text = 'Load Application Time';
            app.LoadApplicationTimeEditField = uieditfield(app.SimulationInputsPanel, 'numeric');
            app.LoadApplicationTimeEditField.Value = 1;
            app.SimulationDurationEditFieldLabel = uilabel(app.SimulationInputsPanel);
            app.SimulationDurationEditFieldLabel.Text = ' Simulation Duration';
            app.SimulationDurationEditField = uieditfield(app.SimulationInputsPanel, 'numeric');
            app.SimulationDurationEditField.Value = 3;
            app.TimeStepEditFieldLabel = uilabel(app.SimulationInputsPanel);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.SimulationInputsPanel, 'numeric');
            app.TimeStepEditField.Value = 0.0001;
            app.VoltageWaveformDropDownLabel = uilabel(app.SimulationInputsPanel);
            app.VoltageWaveformDropDownLabel.Text = 'Voltage Waveform';
            app.VoltageWaveformDropDown = uidropdown(app.SimulationInputsPanel);
            app.VoltageWaveformDropDown.Items = {'Step', 'Ramp', 'Pulse', 'Imported File', 'User-defined'};
            app.VoltageWaveformDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltageWaveformDropDownValueChanged, true);
            app.VoltageWaveformDropDown.Value = 'Step';
            app.NumericalMethodDropDownLabel = uilabel(app.SimulationInputsPanel);
            app.NumericalMethodDropDownLabel.Text = 'Numerical Method';
            app.NumericalMethodDropDown = uidropdown(app.SimulationInputsPanel);
            app.NumericalMethodDropDown.Items = {'Euler', 'RK4', 'RK45 (Adaptive)', 'Both'};
            app.NumericalMethodDropDown.Value = 'Both';
            app.LoadTorqueProfileDropDownLabel = uilabel(app.SimulationInputsPanel);
            app.LoadTorqueProfileDropDownLabel.Text = 'Load Torque Profile';
            app.LoadTorqueProfileDropDown = uidropdown(app.SimulationInputsPanel);
            app.LoadTorqueProfileDropDown.Items = {'Constant (T = K)', 'Linear (T ∝ ω)', 'Quadratic (T ∝ ω²)', 'Constant Power (T ∝ 1/ω)', 'Imported File'};
            app.LoadTorqueProfileDropDown.Value = 'Constant (T = K)';
            app.ResetAllButton = uibutton(app.UIFigure, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnimationTab = uitab(app.TabGroup);
            app.AnimationTab.Title = 'Animation';
            app.AnimationAxes = uiaxes(app.AnimationTab);
            zlabel(app.AnimationAxes, 'Z')
            app.TorqueSpeedAxes = uiaxes(app.AnimationTab);
            title(app.TorqueSpeedAxes, 'Torque-Speed Characteristic')
            xlabel(app.TorqueSpeedAxes, 'Rotational Speed (rad/s)')
            ylabel(app.TorqueSpeedAxes, 'Torque (N.m)')
            zlabel(app.TorqueSpeedAxes, 'Z')
            app.TempLabel = uilabel(app.AnimationTab);
            app.TempLabel.Text = 'Temp: 25.0 °C';
            app.Time000sLabel = uilabel(app.AnimationTab);
            app.Time000sLabel.Text = 'Time: 0.00 s';
            app.ElectricalTab = uitab(app.TabGroup);
            app.ElectricalTab.Title = 'Electrical';
            app.UIAxes = uiaxes(app.ElectricalTab);
            title(app.UIAxes, 'Armature Current vs Time')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Armature Current')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.ElectricalTab);
            title(app.UIAxes_2, 'Applied Voltage vs Time')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Applied Voltage')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.ElectricalTab);
            title(app.UIAxes_3, 'Back-EMF vs Time')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Back-EMF')
            zlabel(app.UIAxes_3, 'Z')
            app.MechanicalTab = uitab(app.TabGroup);
            app.MechanicalTab.Title = 'Mechanical';
            app.UIAxes_4 = uiaxes(app.MechanicalTab);
            title(app.UIAxes_4, 'Motor Speed vs Time')
            xlabel(app.UIAxes_4, 'Time')
            ylabel(app.UIAxes_4, 'Motor Speed')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.MechanicalTab);
            title(app.UIAxes_5, 'Angular Position vs Time')
            xlabel(app.UIAxes_5, 'Time')
            ylabel(app.UIAxes_5, 'Angular Position')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.MechanicalTab);
            title(app.UIAxes_6, 'Electromagnetic Torque & Load Torque vs Time')
            xlabel(app.UIAxes_6, 'Time')
            ylabel(app.UIAxes_6, 'Torque')
            zlabel(app.UIAxes_6, 'Z')
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.UIAxes_7 = uiaxes(app.AnalysisTab);
            title(app.UIAxes_7, 'Euler & RK4 Speed Responses vs Time')
            xlabel(app.UIAxes_7, 'Time')
            ylabel(app.UIAxes_7, 'Motor Speed (rad/s)')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.AnalysisTab);
            title(app.UIAxes_8, 'Numerical Error vs Time')
            xlabel(app.UIAxes_8, 'Time')
            ylabel(app.UIAxes_8, 'Numerical Error')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.ButtonDownFcn = createCallbackFcn(app, @UIAxes_8ButtonDown, true);
            app.UIAxes_9 = uiaxes(app.AnalysisTab);
            title(app.UIAxes_9, 'Maximum Error vs Time-Step Size')
            xlabel(app.UIAxes_9, 'Time-Step Size')
            ylabel(app.UIAxes_9, 'Maximum Error')
            zlabel(app.UIAxes_9, 'Z')
            app.DataTable = uitab(app.TabGroup);
            app.DataTable.Title = 'Data Table';
            app.UITable = uitable(app.DataTable);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.ExportResultsButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportResultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButton_2Pushed, true);
            app.ExportResultsButton_2.Text = 'Export Results';
            app.ImportWaveformButton = uibutton(app.UIFigure, 'push');
            app.ImportWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @ImportWaveformButtonPushed, true);
            app.ImportWaveformButton.Text = 'Import Waveform';
            app.StatusLabel_4 = uilabel(app.UIFigure);
            app.StatusLabel_4.Text = 'Status';
            app.CompareMethodsButton = uibutton(app.UIFigure, 'push');
            app.CompareMethodsButton.ButtonPushedFcn = createCallbackFcn(app, @CompareMethodsButtonPushed, true);
            app.CompareMethodsButton.Text = 'Compare Methods';
            app.StatusLabel_3 = uilabel(app.UIFigure);
            app.StatusLabel_3.Text = 'Status';
            app.TimeStepStudyButton = uibutton(app.UIFigure, 'push');
            app.TimeStepStudyButton.ButtonPushedFcn = createCallbackFcn(app, @TimeStepStudyButtonPushed, true);
            app.TimeStepStudyButton.Text = 'Time-Step Study';
            app.StatusLabel_2 = uilabel(app.UIFigure);
            app.StatusLabel_2.Text = 'Status';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.EnableCurrentLimitCheckBox = uicheckbox(app.UIFigure);
            app.EnableCurrentLimitCheckBox.Text = 'Current Limit';
            app.MaxCurrentAEditFieldLabel = uilabel(app.UIFigure);
            app.MaxCurrentAEditFieldLabel.Text = 'Max Current (A)';
            app.MaxCurrentAEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxCurrentAEditField.Value = 15;
            app.ImportMeasuredDataButton = uibutton(app.UIFigure, 'push');
            app.ImportMeasuredDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMeasuredDataButtonPushed, true);
            app.ImportMeasuredDataButton.Text = 'Import Measured Data';
            app.EnableThermalModelCheckBox = uicheckbox(app.UIFigure);
            app.EnableThermalModelCheckBox.Text = 'Thermal Model';
            app.EnableCoulombFrictionCheckBox = uicheckbox(app.UIFigure);
            app.EnableCoulombFrictionCheckBox.Text = 'Coulomb Friction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DCMotorSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end